classdef MAIN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BMMtFUSSimulatorUIFigure    matlab.ui.Figure
        AcousticsPanel              matlab.ui.container.Panel
        frequencyEdit               matlab.ui.control.NumericEditField
        voxelEdit                   matlab.ui.control.NumericEditField
        pulsesEdit                  matlab.ui.control.NumericEditField
        FreqMHzLabel_2              matlab.ui.control.Label
        VoxelmmLabel_2              matlab.ui.control.Label
        ofpulsesLabel_2             matlab.ui.control.Label
        TargetPanel                 matlab.ui.container.Panel
        targetDV1Edit               matlab.ui.control.NumericEditField
        targetAP1Edit               matlab.ui.control.NumericEditField
        targetML1Edit               matlab.ui.control.NumericEditField
        DVLabel_2                   matlab.ui.control.Label
        APLabel_2                   matlab.ui.control.Label
        MLLabel_2                   matlab.ui.control.Label
        targetDV2Edit               matlab.ui.control.NumericEditField
        targetAP2Edit               matlab.ui.control.NumericEditField
        targetML2Edit               matlab.ui.control.NumericEditField
        target2Check                matlab.ui.control.CheckBox
        stLabel                     matlab.ui.control.Label
        TransducerPanel             matlab.ui.container.Panel
        transducerDVEdit            matlab.ui.control.NumericEditField
        transducerAPEdit            matlab.ui.control.NumericEditField
        transducerMLEdit            matlab.ui.control.NumericEditField
        DVLabel_3                   matlab.ui.control.Label
        APLabel_3                   matlab.ui.control.Label
        MLLabel_3                   matlab.ui.control.Label
        transducerREdit             matlab.ui.control.NumericEditField
        RmmLabel                    matlab.ui.control.Label
        Skullbregmaat000Panel       matlab.ui.container.Panel
        skullDirButton              matlab.ui.control.Button
        skullDirEdit                matlab.ui.control.EditField
        CTCheck                     matlab.ui.control.CheckBox
        skullCutEdit                matlab.ui.control.NumericEditField
        CutskullatDVLabel           matlab.ui.control.Label
        ByGKookLabel                matlab.ui.control.Label
        binaryDirButton             matlab.ui.control.Button
        binaryDirEdit               matlab.ui.control.EditField
        BMMtFUSSimulatorver11Label  matlab.ui.control.Label
        resourceDrop                matlab.ui.control.DropDown
        runButton                   matlab.ui.control.Button
        step1Panel                  matlab.ui.container.Panel
        considerSkullCheck          matlab.ui.control.CheckBox
        TRrecordingLabel            matlab.ui.control.Label
        BuildalensLabel             matlab.ui.control.Label
        fig03                       matlab.ui.control.Image
        lensCheck                   matlab.ui.control.CheckBox
        ValiditydegvxLabel          matlab.ui.control.Label
        lensValidity                matlab.ui.control.NumericEditField
        fig02                       matlab.ui.control.Image
        fig04                       matlab.ui.control.Image
        withoutSkullCheck           matlab.ui.control.CheckBox
        withSkullCheck              matlab.ui.control.CheckBox
        ReconstructLabel            matlab.ui.control.Label
        planeWaveCheck              matlab.ui.control.CheckBox
        SimulationspacePanel        matlab.ui.container.Panel
        spaceAutoButton             matlab.ui.control.Button
        timeEdit                    matlab.ui.control.NumericEditField
        PMLEdit                     matlab.ui.control.NumericEditField
        xMinEdit                    matlab.ui.control.NumericEditField
        xMaxEdit                    matlab.ui.control.NumericEditField
        yMinEdit                    matlab.ui.control.NumericEditField
        yMaxEdit                    matlab.ui.control.NumericEditField
        zMinEdit                    matlab.ui.control.NumericEditField
        zMaxEdit                    matlab.ui.control.NumericEditField
        TimesLabel                  matlab.ui.control.Label
        PMLmmLabel                  matlab.ui.control.Label
        Label_7                     matlab.ui.control.Label
        Label_8                     matlab.ui.control.Label
        Label_9                     matlab.ui.control.Label
        xmmLabel_4                  matlab.ui.control.Label
        ymmLabel_4                  matlab.ui.control.Label
        zmmLabel_4                  matlab.ui.control.Label
        MediumpropertyPanel         matlab.ui.container.Panel
        mediumSpeedEdit             matlab.ui.control.NumericEditField
        mediumDensityEdit           matlab.ui.control.NumericEditField
        mediumAlphaEdit             matlab.ui.control.NumericEditField
        MediumLabel                 matlab.ui.control.Label
        boneSpeedEdit               matlab.ui.control.NumericEditField
        boneDensityEdit             matlab.ui.control.NumericEditField
        boneAlphaEdit               matlab.ui.control.NumericEditField
        BoneLabel                   matlab.ui.control.Label
        LensLabel                   matlab.ui.control.Label
        ResinLabel                  matlab.ui.control.Label
        lensSpeedEdit               matlab.ui.control.NumericEditField
        lensDensityEdit             matlab.ui.control.NumericEditField
        lensAlphaEdit               matlab.ui.control.NumericEditField
        resinSpeedEdit              matlab.ui.control.NumericEditField
        resinDensityEdit            matlab.ui.control.NumericEditField
        resinAlphaEdit              matlab.ui.control.NumericEditField
        mediumPowerEdit             matlab.ui.control.NumericEditField
        fig01                       matlab.ui.control.Image
        PlotcolorRGBLabel           matlab.ui.control.Label
        boneRGBEdit                 matlab.ui.control.NumericEditField
        resinRGBEdit                matlab.ui.control.NumericEditField
        lensRGBEdit                 matlab.ui.control.NumericEditField
        cmsLabel                    matlab.ui.control.Label
        kgm3Label                   matlab.ui.control.Label
        dBMHzcmLabel_2              matlab.ui.control.Label
        AlphapowerLabel             matlab.ui.control.Label
        DVisoppositetoconventionthusisinthebrainLabel  matlab.ui.control.Label
        DataformatPanel             matlab.ui.container.Panel
        saveDirButton               matlab.ui.control.Button
        saveDirEdit                 matlab.ui.control.EditField
        nameAutoButton              matlab.ui.control.Button
        fileDirEdit                 matlab.ui.control.EditField
        Label_11                    matlab.ui.control.Label
        formatDrop                  matlab.ui.control.DropDown
        colorbarDrop                matlab.ui.control.DropDown
        FormatLabel                 matlab.ui.control.Label
        DvolumetricscancautionheavyNACheckBox  matlab.ui.control.CheckBox
        addSliceCheck               matlab.ui.control.CheckBox
        sliceDVEdit                 matlab.ui.control.NumericEditField
        sliceAPEdit                 matlab.ui.control.NumericEditField
        sliceMLEdit                 matlab.ui.control.NumericEditField
        DVLabel_4                   matlab.ui.control.Label
        APLabel_4                   matlab.ui.control.Label
        MLLabel_4                   matlab.ui.control.Label
        ColormapLabel               matlab.ui.control.Label
        loadButton                  matlab.ui.control.Button
        ButtonGroup                 matlab.ui.container.ButtonGroup
        stlToVoxelAButton           matlab.ui.control.RadioButton
        stlToVoxelBButton           matlab.ui.control.RadioButton
        LensMakerAddonLabel         matlab.ui.control.Label
        ImportphasePanel            matlab.ui.container.Panel
        importButton                matlab.ui.control.Button
        phaseDirEdit                matlab.ui.control.EditField
        importedFreq                matlab.ui.control.NumericEditField
        importedVoxel               matlab.ui.control.NumericEditField
        FrequencyMHzLabel           matlab.ui.control.Label
        dxdymmLabel                 matlab.ui.control.Label
        importedResinSpeed          matlab.ui.control.NumericEditField
        ResinsoundspeedmsLabel      matlab.ui.control.Label
        importedLensSpeed           matlab.ui.control.NumericEditField
        LenssoundspeedmsLabel       matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        ExportlensPanel             matlab.ui.container.Panel
        dzmmLabel                   matlab.ui.control.Label
        VoxelvaliditydegpxLabel     matlab.ui.control.Label
        voxelValidity               matlab.ui.control.NumericEditField
        calculateButton             matlab.ui.control.Button
        TotallensthicknessmmLabel   matlab.ui.control.Label
        totalLensThk                matlab.ui.control.NumericEditField
        zPixelEdit                  matlab.ui.control.NumericEditField
        generateButton              matlab.ui.control.Button
        outDirButton                matlab.ui.control.Button
        outputDirEdit               matlab.ui.control.EditField
        filenameEdit                matlab.ui.control.EditField
        FileNameLabel_2             matlab.ui.control.Label
        stlLabel                    matlab.ui.control.Label
        Label_10                    matlab.ui.control.Label
        CollimatorPanel             matlab.ui.container.Panel
        tipMLEdit                   matlab.ui.control.NumericEditField
        tipAPEdit                   matlab.ui.control.NumericEditField
        tipOREdit                   matlab.ui.control.NumericEditField
        tipORLabel                  matlab.ui.control.Label
        tipMLLabel                  matlab.ui.control.Label
        tipAPLabel                  matlab.ui.control.Label
        tipIRLabel                  matlab.ui.control.Label
        Image2                      matlab.ui.control.Image
        tipIREdit                   matlab.ui.control.NumericEditField
        BasethicknessmmLabel        matlab.ui.control.Label
        resinBaseThkEdit            matlab.ui.control.NumericEditField
        collimatorCheck             matlab.ui.control.CheckBox
        ResourceLabel               matlab.ui.control.Label
        stlreadfnLabel              matlab.ui.control.Label
        progressLabel               matlab.ui.control.Label
        CollectionvolumePanel       matlab.ui.container.Panel
        xViewMinEdit                matlab.ui.control.NumericEditField
        xViewMaxEdit                matlab.ui.control.NumericEditField
        yViewMinEdit                matlab.ui.control.NumericEditField
        yViewMaxEdit                matlab.ui.control.NumericEditField
        zViewMinEdit                matlab.ui.control.NumericEditField
        zViewMaxEdit                matlab.ui.control.NumericEditField
        xmmLabel_3                  matlab.ui.control.Label
        ymmLabel_3                  matlab.ui.control.Label
        zmmLabel_3                  matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        collectAutoButton           matlab.ui.control.Button
    end


    methods (Access = private)
        
        function saveState(app,path)
            state.frequencyEdit = app.frequencyEdit.Value;
            state.voxel = app.voxelEdit.Value;
            state.pulses = app.pulsesEdit.Value;
            state.targetDV1 = app.targetDV1Edit.Value;
            state.targetAP1 = app.targetAP1Edit.Value;
            state.targetML1 = app.targetML1Edit.Value;
            state.targetDV2 = app.targetDV2Edit.Value;
            state.targetAP2 = app.targetAP2Edit.Value;
            state.targetML2 = app.targetML2Edit.Value;
            state.target2 = app.target2Check.Value;
            state.transducerR = app.transducerREdit.Value;
            state.transducerDV = app.transducerDVEdit.Value;
            state.transducerAP = app.transducerAPEdit.Value;
            state.transducerML = app.transducerMLEdit.Value;
            state.collimator = app.collimatorCheck.Value;
            state.tipML = app.tipMLEdit.Value;
            state.tipAP = app.tipAPEdit.Value;
            state.tipOR = app.tipOREdit.Value;
            state.skullDir = app.skullDirEdit.Value;
            state.CT = app.CTCheck.Value;
            state.skullCut = app.skullCutEdit.Value;
            state.considerSkull = app.considerSkullCheck.Value;
            state.lens = app.lensCheck.Value;
            state.lensValidity = app.lensValidity.Value;
            state.withSkull = app.withSkullCheck.Value;
            state.withoutSkull = app.withoutSkullCheck.Value;
            state.time = app.timeEdit.Value;
            state.PML = app.PMLEdit.Value;
            state.xMin = app.xMinEdit.Value;
            state.xMax = app.xMaxEdit.Value;
            state.yMin = app.yMinEdit.Value;
            state.yMax = app.yMaxEdit.Value;
            state.zMin = app.zMinEdit.Value;
            state.zMax = app.zMaxEdit.Value;
            state.mediumSpeed = app.mediumSpeedEdit.Value;
            state.mediumDensity = app.mediumDensityEdit.Value;
            state.mediumAlpha = app.mediumAlphaEdit.Value;
            state.boneSpeed = app.boneSpeedEdit.Value;
            state.boneDensity = app.boneDensityEdit.Value;
            state.boneAlpha = app.boneAlphaEdit.Value;
            state.lensSpeed = app.lensSpeedEdit.Value;
            state.lensDensity = app.lensDensityEdit.Value;
            state.lensAlpha = app.lensAlphaEdit.Value;
            state.resinSpeed = app.resinSpeedEdit.Value;
            state.resinDensity = app.resinDensityEdit.Value;
            state.resinAlpha = app.resinAlphaEdit.Value;
            state.mediumPower = app.mediumPowerEdit.Value;
            state.skullRGB = app.boneRGBEdit.Value;
            state.resinRGB = app.resinRGBEdit.Value;
            state.lensRGB = app.lensRGBEdit.Value;
            state.saveDir = app.saveDirEdit.Value;
            state.fileName = app.fileDirEdit.Value;
            state.xViewMin = app.xViewMinEdit.Value;
            state.xViewMax = app.xViewMaxEdit.Value;
            state.yViewMin = app.yViewMinEdit.Value;
            state.yViewMax = app.yViewMaxEdit.Value;
            state.zViewMin = app.zViewMinEdit.Value;
            state.zViewMax = app.zViewMaxEdit.Value;
            state.format = app.formatDrop.Value;
            state.colorbar = app.colorbarDrop.Value;
            state.addSlice = app.addSliceCheck.Value;
            state.sliceDV = app.sliceDVEdit.Value;
            state.sliceAP = app.sliceAPEdit.Value;
            state.sliceML = app.sliceMLEdit.Value;
            state.planeWave = app.planeWaveCheck.Value;
            state.stlToVoxelA = app.stlToVoxelAButton.Value;
            state.tipIR = app.tipIREdit.Value;
            state.resinBaseThk = app.resinBaseThkEdit.Value;
            save(path,'state');
        end
        
        function loadState(app, file, path)
            stateFile = [path, file];
            load(stateFile,'state');
            app.frequencyEdit.Value = state.frequencyEdit;
            app.voxelEdit.Value = state.voxel;
            app.pulsesEdit.Value = state.pulses;
            app.targetDV1Edit.Value = state.targetDV1;
            app.targetAP1Edit.Value = state.targetAP1;
            app.targetML1Edit.Value = state.targetML1;
            app.targetDV2Edit.Value = state.targetDV2;
            app.targetAP2Edit.Value = state.targetAP2;
            app.targetML2Edit.Value = state.targetML2;
            app.target2Check.Value = state.target2;
            app.transducerREdit.Value = state.transducerR;
            app.transducerDVEdit.Value = state.transducerDV;
            app.transducerAPEdit.Value = state.transducerAP;
            app.transducerMLEdit.Value = state.transducerML;
            app.collimatorCheck.Value = state.collimator;
            app.tipMLEdit.Value = state.tipML;
            app.tipAPEdit.Value = state.tipAP;
            app.tipOREdit.Value = state.tipOR;
            app.skullDirEdit.Value = state.skullDir;
            app.CTCheck.Value = state.CT;
            app.skullCutEdit.Value = state.skullCut;
            app.considerSkullCheck.Value = state.considerSkull;
            app.lensCheck.Value = state.lens;
            app.lensValidity.Value = state.lensValidity;
            app.withSkullCheck.Value = state.withSkull;
            app.withoutSkullCheck.Value = state.withoutSkull;
            app.timeEdit.Value = state.time;
            app.PMLEdit.Value = state.PML;
            app.xMinEdit.Value = state.xMin;
            app.xMaxEdit.Value = state.xMax;
            app.yMinEdit.Value = state.yMin;
            app.yMaxEdit.Value = state.yMax;
            app.zMinEdit.Value = state.zMin;
            app.zMaxEdit.Value = state.zMax;
            app.mediumSpeedEdit.Value = state.mediumSpeed;
            app.mediumDensityEdit.Value = state.mediumDensity;
            app.mediumAlphaEdit.Value = state.mediumAlpha;
            app.boneSpeedEdit.Value = state.boneSpeed;
            app.boneDensityEdit.Value = state.boneDensity;
            app.boneAlphaEdit.Value = state.boneAlpha;
            app.lensSpeedEdit.Value = state.lensSpeed;
            app.lensDensityEdit.Value = state.lensDensity;
            app.lensAlphaEdit.Value = state.lensAlpha;
            app.resinSpeedEdit.Value = state.resinSpeed;
            app.resinDensityEdit.Value = state.resinDensity;
            app.resinAlphaEdit.Value = state.resinAlpha;
            app.mediumPowerEdit.Value = state.mediumPower;
            app.boneRGBEdit.Value = state.skullRGB;
            app.resinRGBEdit.Value = state.resinRGB;
            app.lensRGBEdit.Value = state.lensRGB;
            app.saveDirEdit.Value = state.saveDir;
            app.fileDirEdit.Value = state.fileName;
            app.xViewMinEdit.Value = state.xViewMin;
            app.xViewMaxEdit.Value = state.xViewMax;
            app.yViewMinEdit.Value = state.yViewMin;
            app.yViewMaxEdit.Value = state.yViewMax;
            app.zViewMinEdit.Value = state.zViewMin;
            app.zViewMaxEdit.Value = state.zViewMax;
            app.formatDrop.Value = state.format;
            app.colorbarDrop.Value = state.colorbar;
            app.addSliceCheck.Value = state.addSlice;
            app.sliceDVEdit.Value = state.sliceDV;
            app.sliceAPEdit.Value = state.sliceAP;
            app.sliceMLEdit.Value = state.sliceML;
            app.planeWaveCheck.Value = state.planeWave;
            if state.stlToVoxelA
                app.stlToVoxelAButton.Value = 1;
            else
                app.stlToVoxelBButton.Value = 1;
            end
            app.tipIREdit.Value = state.tipIR;
            app.resinBaseThkEdit.Value = state.resinBaseThk;
            drawnow
        end
        
        function error_flag = checkInput(app)
            error_flag=0;
            if (app.xViewMinEdit.Value<app.xMinEdit.Value)|(app.xViewMaxEdit.Value>app.xMaxEdit.Value) ...
                    |(app.yViewMinEdit.Value<app.yMinEdit.Value)|(app.yViewMaxEdit.Value>app.yMaxEdit.Value)...
                    |(app.zViewMinEdit.Value<app.zMinEdit.Value)|(app.zViewMaxEdit.Value>app.zMaxEdit.Value)
                errordlg('Monitoring window exceeds simulation volume.');
                error_flag=1; return
            end
            if (app.transducerMLEdit.Value-app.transducerREdit.Value<app.xMinEdit.Value)...
                    |(app.transducerMLEdit.Value+app.transducerREdit.Value>app.xMaxEdit.Value)...
                    |(app.transducerAPEdit.Value-app.transducerREdit.Value<app.yMinEdit.Value)...
                    |(app.transducerAPEdit.Value+app.transducerREdit.Value>app.yMaxEdit.Value)...
                    |(app.transducerDVEdit.Value<app.zMinEdit.Value)|(app.transducerDVEdit.Value>app.zMaxEdit.Value)
                errordlg('Transducer exceeds simulation volume.');
                error_flag=1; return
            end
            if (app.tipMLEdit.Value-app.tipOREdit.Value<app.xMinEdit.Value)...
                    |(app.tipMLEdit.Value+app.tipOREdit.Value>app.xMaxEdit.Value)...
                    |(app.tipAPEdit.Value-app.tipOREdit.Value<app.yMinEdit.Value)...
                    |(app.tipAPEdit.Value+app.tipOREdit.Value>app.yMaxEdit.Value)
                errordlg('Collimator exceeds simulation volume');
                error_flag=1; return
            end
            if (app.targetML1Edit.Value<app.xMinEdit.Value)|(app.targetML1Edit.Value>app.xMaxEdit.Value)...
                    |(app.targetAP1Edit.Value<app.yMinEdit.Value)|(app.targetAP1Edit.Value>app.yMaxEdit.Value)...
                    |(app.targetDV1Edit.Value<app.zMinEdit.Value)|(app.targetDV1Edit.Value>app.zMaxEdit.Value)...
                errordlg('Target1 exceeds simulation volume');
                error_flag=1; return
            end
            if app.target2Check.Value &...
                    ((app.targetML2Edit.Value<app.xMinEdit.Value)|(app.targetML2Edit.Value>app.xMaxEdit.Value)...
                    |(app.targetAP2Edit.Value<app.yMinEdit.Value)|(app.targetAP2Edit.Value>app.yMaxEdit.Value)...
                    |(app.targetDV2Edit.Value<app.zMinEdit.Value)|(app.targetDV2Edit.Value>app.zMaxEdit.Value))
                errordlg('Target2 exceeds simulation volume');
                error_flag=1; return
            end
            if app.addSliceCheck.Value &...
                    ((app.sliceMLEdit.Value<app.xMinEdit.Value)|(app.sliceMLEdit.Value>app.xMaxEdit.Value)...
                    |(app.sliceAPEdit.Value<app.yMinEdit.Value)|(app.sliceAPEdit.Value>app.yMaxEdit.Value)...
                    |(app.sliceDVEdit.Value<app.zMinEdit.Value)|(app.sliceDVEdit.Value>app.zMaxEdit.Value))
                errordlg('Slice point exceeds simulation volume');
                error_flag=1; return
            end
        end
        
        function [collimator_model,upper_model,error_flag] = makeCollimator(app,grid,transducer,skull_model)
            error_flag=0;
            Nx=numel(grid.x); Ny=numel(grid.y); Nz=numel(grid.z);
            collimator_model=zeros(Nx,Ny,Nz,'logical');
            upper_model=zeros(Nx,Ny,Nz,'logical');
            tip_ML = app.tipMLEdit.Value; tip_AP = app.tipAPEdit.Value;
            tip_OR = app.tipOREdit.Value; tip_IR = app.tipIREdit.Value;
            skullShade=zeros(Nx,Ny,Nz,'logical'); shaded=zeros(Nx,Ny,'logical');
            tip=(grid.X-tip_ML).^2+(grid.Y-tip_AP).^2<=tip_OR.^2; tip=squeeze(tip(:,:,1));
            tipOpen = 1;
            for iterZ=numel(grid.z):(-1):1
                shaded(skull_model(:,:,iterZ))=1;
                skullShade(:,:,iterZ)=shaded;
                if tipOpen & isempty(find(~shaded&tip))
                    tipOpen = 0;
                    tip_idxZ = iterZ+1;
                end
            end
            if app.resinBaseThkEdit.Value
                lens_add=(grid.X-transducer.ML).^2+(grid.Y-transducer.AP).^2<=transducer.r.^2 ...
                    &(grid.Z<=transducer.DV)&(grid.Z>=(transducer.DV-app.resinBaseThkEdit.Value)) ...
                    &~skullShade;
                collimator_model(lens_add)=1;
                if ~app.collimatorCheck.Value
                    upper_model=(grid.X-transducer.ML).^2+(grid.Y-transducer.AP).^2<=transducer.r.^2 ...
                        &(grid.Z>transducer.DV);
                    return
                end
            end
            if tipOpen % return error if collimator is not closed through z scan
                errordlg('Collimator tip reaches out of the skull.');
                error_flag=1; return
            end
            iterZi=find(grid.z==transducer.DV); iterZf = tip_idxZ;
            for iterZ=iterZi:(-1):iterZf
                rate=(iterZ-iterZi)/(iterZf-iterZi); % 0-1
                slice_ML=transducer.ML+rate*(tip_ML-transducer.ML);
                slice_AP=transducer.AP+rate*(tip_AP-transducer.AP);
                switch tip_IR
                    case 0 % filled transducer
                        slice_or = transducer.r+rate*(tip_OR-transducer.r);
                        col_slice = (grid.X-slice_ML).^2+(grid.Y-slice_AP).^2<=slice_or.^2 & grid.Z==grid.z(iterZ);
                    otherwise % hollow transducer
                        slice_ir = transducer.r+rate*(tip_IR-transducer.r);
                        slice_or = slice_ir+(tip_OR-tip_IR);
                        col_slice = (grid.X-slice_ML).^2+(grid.Y-slice_AP).^2<=slice_or.^2 ...
                            & (grid.X-slice_ML).^2+(grid.Y-slice_AP).^2>slice_ir.^2 & grid.Z==grid.z(iterZ);
                end
                collimator_model(~skullShade&col_slice)=1;
            end
            switch tip_IR
                case 0
                    upper_model=(grid.X-transducer.ML).^2+(grid.Y-transducer.AP).^2<=transducer.r.^2 ...
                        &(grid.Z>transducer.DV);
                otherwise
                    if app.resinBaseThkEdit.Value
                        upper_model=(grid.X-transducer.ML).^2+(grid.Y-transducer.AP).^2<=(transducer.r+tip_OR-tip_IR).^2 ...
                            &(grid.Z>transducer.DV);
                    end
            end
        end
        
        function b = fitCoordinate(app,a,vector,vox)
            b = vector(find(round(1e4*abs(vector-a))/1e4 <= round(1e4*vox/2)/1e4));
            if numel(b)>1
                b=b(1);
            end
        end
        
        function [grid,transducer,target,model,medium,view,error_flag] = buildSimulationInput(app)
            error_flag = 0;
            %% grid setup
            vox = app.voxelEdit.Value;
            pmlSize = ceil(app.PMLEdit.Value/vox);
            xMin = app.xMinEdit.Value-vox*pmlSize;
            yMin = app.yMinEdit.Value-vox*pmlSize;
            zMin = app.zMinEdit.Value-vox*pmlSize;
            xMax = app.xMaxEdit.Value+vox*pmlSize;
            yMax = app.yMaxEdit.Value+vox*pmlSize;
            zMax = app.zMaxEdit.Value+vox*pmlSize;
            xMax = xMin+vox*ceil((xMax-xMin)/vox);
            yMax = yMin+vox*ceil((yMax-yMin)/vox);
            zMax = zMin+vox*ceil((zMax-zMin)/vox);
            grid.x = xMin:vox:xMax; % ML direction, [mm]
            grid.y = yMin:vox:yMax; % AP direction
            grid.z = zMin:vox:zMax; % -DV direction
            grid.vx = vox;
            [grid.Y, grid.X, grid.Z] = meshgrid(grid.y, grid.x, grid.z);
            Nx = numel(grid.x); Ny = numel(grid.y); Nz = numel(grid.z);
            %% transducer setup
            transducer.DV = app.fitCoordinate(app.transducerDVEdit.Value,grid.z,vox); % fit coordinate values into grid
            transducer.AP = app.fitCoordinate(app.transducerAPEdit.Value,grid.y,vox);
            transducer.ML = app.fitCoordinate(app.transducerMLEdit.Value,grid.x,vox);
            transducer.r = app.transducerREdit.Value;
            transducer.model = (grid.X-transducer.ML).^2+(grid.Y-transducer.AP).^2<=transducer.r^2 & grid.Z==transducer.DV;
            transducer.x = grid.x((grid.x-transducer.ML).^2 <=transducer.r^2); transducer.y = grid.y((grid.y-transducer.AP).^2 <= transducer.r^2);
            [transducer.Y, transducer.X] = meshgrid(transducer.y, transducer.x);
            %% target setup
            target.DV1 = app.fitCoordinate(app.targetDV1Edit.Value,grid.z,vox);
            target.AP1 = app.fitCoordinate(app.targetAP1Edit.Value,grid.y,vox);
            target.ML1 = app.fitCoordinate(app.targetML1Edit.Value,grid.x,vox);
            target.model = grid.X==target.ML1 & grid.Y==target.AP1 & grid.Z==target.DV1;
            if app.target2Check.Value
                target.DV2 = app.fitCoordinate(app.targetDV2Edit.Value,grid.z,vox);
                target.AP2 = app.fitCoordinate(app.targetAP2Edit.Value,grid.y,vox);
                target.ML2 = app.fitCoordinate(app.targetML2Edit.Value,grid.x,vox);
                target.model = target.model + (grid.X==target.ML2 & grid.Y==target.AP2 & grid.Z==target.DV2);
            end
            %% model setup
            model.skull = zeros(Nx,Ny,Nz,'logical');
            model.lens = zeros(Nx,Ny,Nz,'logical');
            model.collimator = zeros(Nx,Ny,Nz,'logical'); % when filling upper side of the collimator to elliminate reflection during TR
            model.upper=zeros(Nx,Ny,Nz,'logical');
            switch app.stlToVoxelAButton.Value
                case 1
                    if isfile([app.saveDirEdit.Value,app.fileDirEdit.Value,'\voxelizedSkullA.mat'])
                        imported = load([app.saveDirEdit.Value,app.fileDirEdit.Value,'\voxelizedSkullA.mat'],'model');
                        model.skull = imported.model.skull; clear('imported');
                    else
                        model.skull = stlToVoxelA(grid.x,grid.y,grid.z,app.skullDirEdit.Value,'xyz');
                    end
                case 0
                    if isfile([app.saveDirEdit.Value,app.fileDirEdit.Value,'\voxelizedSkullB.mat'])
                        imported = load([app.saveDirEdit.Value,app.fileDirEdit.Value,'\voxelizedSkullB.mat'],'model');
                        model.skull = imported.model.skull; clear('imported');
                    else
                        model.skull = stlToVoxelB(grid.x,grid.y,grid.z,app.skullDirEdit.Value);
                    end
            end
            if app.skullCutEdit.Value
                model.skull(find(grid.Z<app.considerSkullCheck.Value))=0;
            end
            if app.collimatorCheck.Value | app.resinBaseThkEdit.Value
                [model.collimator,model.upper,error_flag] = app.makeCollimator(grid,transducer,model.skull);
                if error_flag
                    target=0; view=0; return
                end
            end
            %% medium setup            
            medium.step1=cell(2,1); % medium used for TR recording during step1, bone(B)/collimator(C), 1 is X, 2 is O
            for iterB=1:2
                medium.step1{iterB}.sound_speed = app.mediumSpeedEdit.Value*ones(Nx,Ny,Nz,'single');
                medium.step1{iterB}.density = app.mediumDensityEdit.Value*ones(Nx,Ny,Nz,'single');
                medium.step1{iterB}.alpha_coeff = app.mediumAlphaEdit.Value*ones(Nx,Ny,Nz,'single'); % Study k-wave manual pp32-33.
                medium.step1{iterB}.alpha_power = app.mediumPowerEdit.Value;
                if iterB==2
                    medium.step1{iterB}.sound_speed(model.skull) = app.boneSpeedEdit.Value;
                    medium.step1{iterB}.density(model.skull) = app.boneDensityEdit.Value;
                    medium.step1{iterB}.alpha_coeff(model.skull) = app.boneAlphaEdit.Value;
                end
                medium.step1{iterB}.sound_speed(model.collimator) = app.resinSpeedEdit.Value;
                medium.step1{iterB}.density(model.collimator) = app.resinDensityEdit.Value;
                medium.step1{iterB}.alpha_coeff(model.collimator) = app.resinAlphaEdit.Value;
                medium.step1{iterB}.sound_speed(model.upper) = app.resinSpeedEdit.Value;
                medium.step1{iterB}.density(model.upper) = app.resinDensityEdit.Value;
                medium.step1{iterB}.alpha_coeff(model.upper) = app.resinAlphaEdit.Value;
            end
            %% viewing grid setup (= Sensor position, yz,xz,xy slices through a focus)
            xViewMin = app.fitCoordinate(app.xViewMinEdit.Value,grid.x,vox);
            xViewMax = app.fitCoordinate(app.xViewMaxEdit.Value,grid.x,vox);
            yViewMin = app.fitCoordinate(app.yViewMinEdit.Value,grid.y,vox);
            yViewMax = app.fitCoordinate(app.yViewMaxEdit.Value,grid.y,vox);
            zViewMin = app.fitCoordinate(app.zViewMinEdit.Value,grid.z,vox);
            zViewMax = app.fitCoordinate(app.zViewMaxEdit.Value,grid.z,vox);
            view.x=grid.x(find(grid.x==xViewMin):find(grid.x==xViewMax));
            view.y=grid.y(find(grid.y==yViewMin):find(grid.y==yViewMax));
            view.z=grid.z(find(grid.z==zViewMin):find(grid.z==zViewMax));
            view.model = (grid.X>=xViewMin&grid.X<=xViewMax)&...
                (grid.Y>=yViewMin&grid.Y<=yViewMax)&...
                (grid.Z>=zViewMin&grid.Z<=zViewMax);
            view.slice=cell(3,1);
            for iterS=1:3
                view.slice{iterS}.yz=zeros(Nx,Ny,Nz,'logical');
                view.slice{iterS}.xz=zeros(Nx,Ny,Nz,'logical');
                view.slice{iterS}.xy=zeros(Nx,Ny,Nz,'logical');
            end
            view.slice{1}.yz=view.model&grid.X==target.ML1;
            view.slice{1}.xz=view.model&grid.Y==target.AP1;
            view.slice{1}.xy=view.model&grid.Z==target.DV1;
            view.slice{1}.ML=target.ML1;
            view.slice{1}.AP=target.AP1;
            view.slice{1}.DV=target.DV1;
            if app.target2Check.Value
                view.slice{2}.yz=view.model&grid.X==target.ML2;
                view.slice{2}.xz=view.model&grid.Y==target.AP2;
                view.slice{2}.xy=view.model&grid.Z==target.DV2;
                view.slice{2}.ML=target.ML2;
                view.slice{2}.AP=target.AP2;
                view.slice{2}.DV=target.DV2;
            end
            if app.addSliceCheck.Value
                slice_DV=app.fitCoordinate(app.sliceDVEdit.Value,grid.z,vox);
                slice_AP=app.fitCoordinate(app.sliceAPEdit.Value,grid.y,vox);
                slice_ML=app.fitCoordinate(app.sliceMLEdit.Value,grid.x,vox);
                view.slice{3}.yz=view.model&grid.X==slice_ML;
                view.slice{3}.xz=view.model&grid.Y==slice_AP;
                view.slice{3}.xy=view.model&grid.Z==slice_DV;
                view.slice{3}.ML=slice_ML;
                view.slice{3}.AP=slice_AP;
                view.slice{3}.DV=slice_DV;
            end
            view.slices = zeros(Nx,Ny,Nz,'logical');
            for iterS=1:3
                view.slices = view.slices | (view.slice{iterS}.yz|view.slice{iterS}.xz|view.slice{iterS}.xy);
            end
            view.regionOfInterest = grid.Z<transducer.DV;
            switch app.formatDrop.Value
                case 'p(t)'
                        view.mode = 'raw'; % recording raw p(t) and u(t)
                case 'max(p(t))'
                        view.mode = 'max'; % recording max(p(t))
            end
        end
        
        function confirm_flag = confirm(app,grid,transducer,target,model,medium,view)
            confirm_flag=0;
            Nx=numel(grid.x); Ny=numel(grid.y); Nz=numel(grid.z);
            rgbUnit = ones(Nx,Ny,Nz)/255;
            color.bkgd=cat(4,255*rgbUnit,255*rgbUnit,255*rgbUnit);
            color.view=cat(4,255*rgbUnit,0*rgbUnit,0*rgbUnit);
            color.transducer=cat(4,0*rgbUnit,255*rgbUnit,0*rgbUnit);
            color.bone=cat(4,app.boneRGBEdit.Value*rgbUnit,app.boneRGBEdit.Value*rgbUnit,app.boneRGBEdit.Value*rgbUnit);
            color.resin=cat(4,app.resinRGBEdit.Value*rgbUnit,app.resinRGBEdit.Value*rgbUnit,app.resinRGBEdit.Value*rgbUnit);
            alpha.view=0.25*view.model;
            alpha.transducer=1.0*transducer.model;
            alpha.bone=1.0*model.skull;
            alpha.resin=1.0*model.collimator;
            color.bkgd_yz=squeeze(color.bkgd(grid.x==target.ML1,:,:,:));
            color.bkgd_xz=squeeze(color.bkgd(:,grid.y==target.AP1,:,:));
            color.bkgd_xy=squeeze(color.bkgd(:,:,grid.z==target.DV1,:));
            color.view_yz=squeeze(color.view(grid.x==target.ML1,:,:,:));
            color.view_xz=squeeze(color.view(:,grid.y==target.AP1,:,:));
            color.view_xy=squeeze(color.view(:,:,grid.z==target.DV1,:));
            color.transducer_yz=squeeze(color.transducer(grid.x==target.ML1,:,:,:));
            color.transducer_xz=squeeze(color.transducer(:,grid.y==target.AP1,:,:));
            color.transducer_xy=squeeze(color.transducer(:,:,grid.z==target.DV1,:));
            color.bone_yz=squeeze(color.bone(grid.x==target.ML1,:,:,:));
            color.bone_xz=squeeze(color.bone(:,grid.y==target.AP1,:,:));
            color.bone_xy=squeeze(color.bone(:,:,grid.z==target.DV1,:));
            color.resin_yz=squeeze(color.resin(grid.x==target.ML1,:,:,:));
            color.resin_xz=squeeze(color.resin(:,grid.y==target.AP1,:,:));
            color.resin_xy=squeeze(color.resin(:,:,grid.z==target.DV1,:));
            alpha.view_yz=squeeze(alpha.view(grid.x==target.ML1,:,:));
            alpha.view_xz=squeeze(alpha.view(:,grid.y==target.AP1,:));
            alpha.view_xy=squeeze(alpha.view(:,:,grid.z==target.DV1));
            alpha.transducer_yz=squeeze(alpha.transducer(grid.x==target.ML1,:,:));
            alpha.transducer_xz=squeeze(alpha.transducer(:,grid.y==target.AP1,:));
            alpha.transducer_xy=squeeze(alpha.transducer(:,:,grid.z==target.DV1));
            alpha.bone_yz=squeeze(alpha.bone(grid.x==target.ML1,:,:));
            alpha.bone_xz=squeeze(alpha.bone(:,grid.y==target.AP1,:));
            alpha.bone_xy=squeeze(alpha.bone(:,:,grid.z==target.DV1));
            alpha.resin_yz=squeeze(alpha.resin(grid.x==target.ML1,:,:));
            alpha.resin_xz=squeeze(alpha.resin(:,grid.y==target.AP1,:));
            alpha.resin_xy=squeeze(alpha.resin(:,:,grid.z==target.DV1));
            f=figure; t=tiledlayout(1,3); t.TileSpacing='compact'; t.Padding='compact';
            title(t,'Confirm progression');
            ax1=nexttile; imagesc(grid.y, grid.z, permute(color.bkgd_yz,[2,1,3])); ax1.YDir='normal'; axis image; xlabel('AP [mm]'); ylabel('DV [mm]'); title('ML slice'); hold on;
            h=imagesc(grid.y,grid.z,permute(color.bone_yz,[2,1,3])); set(h,'AlphaData', transpose(alpha.bone_yz));
            h=imagesc(grid.y,grid.z,permute(color.resin_yz,[2,1,3])); set(h,'AlphaData', transpose(alpha.resin_yz));
            h=imagesc(grid.y,grid.z,permute(color.transducer_yz,[2,1,3])); set(h,'AlphaData', transpose(alpha.transducer_yz));
            h=imagesc(grid.y,grid.z,permute(color.view_yz,[2,1,3])); set(h,'AlphaData', transpose(alpha.view_yz));
            hold off;
            ax1=nexttile; imagesc(grid.x, grid.z, permute(color.bkgd_xz,[2,1,3])); ax1.YDir='normal'; axis image; xlabel('ML [mm]'); ylabel('DV [mm]'); title('AP slice'); hold on;
            h=imagesc(grid.x,grid.z,permute(color.bone_xz,[2,1,3])); set(h,'AlphaData', transpose(alpha.bone_xz));
            h=imagesc(grid.x,grid.z,permute(color.resin_xz,[2,1,3])); set(h,'AlphaData', transpose(alpha.resin_xz));
            h=imagesc(grid.x,grid.z,permute(color.transducer_xz,[2,1,3])); set(h,'AlphaData', transpose(alpha.transducer_xz));
            h=imagesc(grid.x,grid.z,permute(color.view_xz,[2,1,3])); set(h,'AlphaData', transpose(alpha.view_xz));
            hold off;
            ax1=nexttile; imagesc(grid.x, grid.y, permute(color.bkgd_xy,[2,1,3])); ax1.YDir='normal'; axis image; xlabel('ML [mm]'); ylabel('AP [mm]'); title('DV slice'); hold on;
            h=imagesc(grid.x,grid.y,permute(color.bone_xy,[2,1,3])); set(h,'AlphaData', transpose(alpha.bone_xy));
            h=imagesc(grid.x,grid.y,permute(color.resin_xy,[2,1,3])); set(h,'AlphaData', transpose(alpha.resin_xy));
            h=imagesc(grid.x,grid.y,permute(color.transducer_xy,[2,1,3])); set(h,'AlphaData', transpose(alpha.transducer_xy));
            h=imagesc(grid.x,grid.y,permute(color.view_xy,[2,1,3])); set(h,'AlphaData', transpose(alpha.view_xy));
            hold off;
            drawnow
            fig = uifigure;
            ui_msg = 'Will you continue the simulation?';
            ui_title = 'Confirm simulation';
            selection = uiconfirm(fig,ui_msg,ui_title,...
                'Options',{'Continue','Cancel'}, ...
                'DefaultOption',1,'CancelOption',2);
            switch selection
                case 'Continue'
                    close(fig);
                    confirm_flag=1;
                case 'Cancel'
                    close(fig);
                    confirm_flag=0;
            end
        end
        
        function CPP_write(app,medium,kgrid,sim)
            freq = 1e6*app.frequencyEdit.Value; %[Hz]
            % file_path = [file_path, '\'];
            run([getkWavePath 'private/getH5Literals']);
            %% write medium parameters
            tic; fprintf('Writing medium parameters... ');
            rho0 = medium.density; eval(['rho0 = ' MATRIX_DATA_TYPE_MATLAB '(rho0);']);
            writeMatrix(sim.filename_in, rho0, 'rho0');
            rho0_sg = interpn(kgrid.x, kgrid.y, kgrid.z, rho0, kgrid.x + kgrid.dx/2, kgrid.y, kgrid.z, '*linear');
            rho0_sg(isnan(rho0_sg)) = rho0(isnan(rho0_sg));
            writeMatrix(sim.filename_in, rho0_sg, 'rho0_sgx');
            rho0_sg = interpn(kgrid.x, kgrid.y, kgrid.z, rho0, kgrid.x, kgrid.y + kgrid.dy/2, kgrid.z, '*linear');
            rho0_sg(isnan(rho0_sg)) = rho0(isnan(rho0_sg));
            writeMatrix(sim.filename_in, rho0_sg, 'rho0_sgy');
            rho0_sg = interpn(kgrid.x, kgrid.y, kgrid.z, rho0, kgrid.x, kgrid.y, kgrid.z + kgrid.dz/2, '*linear');
            rho0_sg(isnan(rho0_sg)) = rho0(isnan(rho0_sg));
            writeMatrix(sim.filename_in, rho0_sg, 'rho0_sgz');
            % clear variable to free memory
            clear('rho0', 'rho0_sg');
            c0 = medium.sound_speed; c_ref = max(c0(:)); c_source = min(c0(:));
            eval(['c0 = ' MATRIX_DATA_TYPE_MATLAB '(c0);']);
            writeMatrix(sim.filename_in, c0, 'c0');
            clear('c0');
            alpha_coeff = medium.alpha_coeff; alpha_power = medium.alpha_power;
            eval(['alpha_coeff = ' MATRIX_DATA_TYPE_MATLAB '(alpha_coeff);']);
            eval(['alpha_power = ' MATRIX_DATA_TYPE_MATLAB '(alpha_power);']);
            writeMatrix(sim.filename_in, alpha_coeff, 'alpha_coeff');
            writeMatrix(sim.filename_in, alpha_power, 'alpha_power');
            clear('c0', 'alpha_coeff', 'alpha_power');
            clear('medium');
            %% write source matrix
            toc; tic; fprintf('Writing source parameters... ');
            p_mask = sim.source_model;
            p_source_index = find(p_mask == 1);
            p_source_index = reshape(p_source_index, [], 1);
            eval(['p_source_index = ' INTEGER_DATA_TYPE_MATLAB '(p_source_index);']);
            writeMatrix(sim.filename_in, p_source_index, 'p_source_index');
            clear p_mask p_source_index;
            if size(sim.source_p,1)>1
                tb_cycles = app.pulsesEdit.Value;
                tb_offset = 10 + (1/freq)*((angle(sim.source_p)+pi)/(2*pi))/kgrid.dt; % given in units of time samples, i.e., actual time divided by dt (+10 is for making offset posotive, there will be an error when negative)
                tb_phase_only = toneBurst(1/kgrid.dt, freq, tb_cycles, 'SignalOffset', tb_offset, 'Envelope', 'Rectangular');
                tb_amplitude = abs(sim.source_p)*ones(1,size(tb_phase_only,2));
                p_source_input = tb_amplitude.*tb_phase_only;
            else
                p_source_input = 1*sin(2*pi*freq*kgrid.t_array);
                if 0 % in case you want to ramp up the source rather than to abruptly turn it on. recommended for avoiding startup transient
                    ramp_length = round((2*pi/freq)/kgrid.dt);
                    p_source_input(1:ramp_length) = p_source_input(1:ramp_length).*(-cos((0:(ramp_length-1))*pi/ramp_length)+1)/2;
                end
            end
            scale = (2*kgrid.dt)/(3*c_source*kgrid.dx);
            p_source_input = scale*p_source_input; % Study k-wave manual pp13-14
            eval(['p_source_input = ' MATRIX_DATA_TYPE_MATLAB '(p_source_input);']);
            writeMatrix(sim.filename_in, p_source_input, 'p_source_input');
            clear('p_source_input');
            p_source_mode = ~strcmp(sim.source_BC, 'dirichlet'); % dirichlet or additive boundary condition for source
            eval(['p_source_mode = ' INTEGER_DATA_TYPE_MATLAB '(p_source_mode);']);
            writeMatrix(sim.filename_in, p_source_mode, 'p_source_mode');
            clear('p_source_mode');
            % create sensor matrix
            toc; tic; fprintf('Writing sensor parameters... ');
            sensor_mask = sim.sensor;
            sensor_mask_index = find(sensor_mask);
            sensor_mask_index = reshape(sensor_mask_index, [], 1);
            eval(['sensor_mask_index = ' INTEGER_DATA_TYPE_MATLAB '(sensor_mask_index);']);
            writeMatrix(sim.filename_in, sensor_mask_index, 'sensor_mask_index');
            clear('sensor_mask', 'sensor_mask_index');
            % WRITE THE GRID PARAMETERS AND FILE ATTRIBUTES
            toc; tic; fprintf('Writing grid parameters and attributes... ');
            pml_x_size = ceil(app.PMLEdit.Value/app.voxelEdit.Value); % must be larger than one wavelength
            pml_y_size = pml_x_size; pml_z_size = pml_x_size;
            pml_x_alpha = 2; pml_y_alpha = 2; pml_z_alpha = 2;
            writeGrid(sim.filename_in, [kgrid.Nx, kgrid.Ny, kgrid.Nz], [kgrid.dx, kgrid.dy, kgrid.dz], ...
                [pml_x_size, pml_y_size, pml_z_size], [pml_x_alpha, pml_y_alpha, pml_z_alpha], kgrid.Nt, kgrid.dt, c_ref);
            writeFlags(sim.filename_in);
            writeAttributes(sim.filename_in);
            clear('kgrid');
            toc;
        end
        
        function data = CPP_read(app,medium,sim,kgrid)
            filename_out = sim.filename_out;
            data_path = app.saveDirEdit.Value;
            switch sim.recordType
                case 'raw' % return p_max, I_sppa, and p (complex pressure having phase data)
                    % load output data from the C++ simulation
                    data.p = h5read(filename_out, '/p');
                    ux = h5read(filename_out, '/ux');
                    Ix_avg = mean(data.p.*ux,2);
                    clear('ux'); % to save RAM
                    uy = h5read(filename_out, '/uy');
                    Iy_avg = mean(data.p.*uy,2);
                    clear('uy');
                    uz = h5read(filename_out, '/uz');
                    Iz_avg = mean(data.p.*uz,2);
                    clear('uz');
                    I_spta = sqrt(Ix_avg.^2+Iy_avg.^2+Iz_avg.^2); 
                    clear('Ix_avg', 'Iy_avg', 'Iz_avg');
                    dutyCycle = (app.pulsesEdit.Value/(1e6*app.frequencyEdit.Value)/(kgrid.Nt*kgrid.dt));
                    data.I_sppa = I_spta/min(1,dutyCycle); % data.I_sppa
                    [data.p_max,idx_max] = max(data.p,[],2); % data.p_max
                    phs = zeros(size(data.p,1),1); phs(1) = 0;
                    refTime = idx_max(1);
                    T = 1/(1e6*app.frequencyEdit.Value);
                    for iter1 = 2:size(data.p,1)
                        dT = double(idx_max(iter1)-refTime)*kgrid.dt;
                        phs(iter1) = 2*pi*mod(dT,T)/T;
                    end
                    data.p = data.p_max.*exp(i*phs); % data.p
                case 'max' % return p_max
                    Nt = kgrid.Nt; N0 = ceil(Nt*0.9);
                    data.p_max = h5read(filename_out, '/p_max');
            end
        end
        
        function data = CPP_run(app,grid,sim)
            %% kgrid SETUP
            Nx=numel(grid.x); Ny=numel(grid.y); Nz=numel(grid.z);
            dx=app.voxelEdit.Value*1e-3; dy=dx; dz=dx; % [mm] to [m]
            kgrid = kWaveGrid(Nx, dx, Ny, dy, Nz, dz);
            t_end = app.timeEdit.Value; %[s] total time of simulation for one propagation, 0 for auto
            if t_end
                kgrid.makeTime(sim.medium.sound_speed,[], t_end);
            else
                kgrid.makeTime(sim.medium.sound_speed,[]);
            end
            %% write input h file
            if ~exist(sim.filename_in, 'file')
                app.CPP_write(sim.medium, kgrid, sim);
            end
            %% run C++ simulation to make output h file 
            if ~exist(sim.filename_out, 'file')
                system_string = 'set OMP_PLACES=cores & ';
                binary_path = app.binaryDirEdit.Value;
                if strcmp(app.resourceDrop.Value,'OMP')
                    binary_name = 'kspaceFirstOrder-OMP.exe';
                elseif strcmp(app.resourceDrop.Value,'CUDA')
                    binary_name = 'kspaceFirstOrder-CUDA.exe';
                end
                switch sim.recordType
                    case 'raw'
                        options_string = '--p_raw --u_raw'; % recording raw p and u
                    case 'max'
                        options_string = '--p_max'; % only recording p_max
                end
                run_string = ['system(''' system_string ' cd /d "' binary_path '" & ' binary_name ' -i "' sim.filename_in '" -o "' sim.filename_out '" ' options_string ''' ,''-echo'');'];
                eval(run_string);
            end
            %% read h file to MATLAB to plot
            data = app.CPP_read(sim.medium, sim, kgrid);
        end
        
        function [lens_model, resin_model, newSource] = makeLens(app,grid,transducer,recon)
            freq = 1e6*app.frequencyEdit.Value; %[Hz]
            vox = grid.vx; %[mm]
            Nx=numel(grid.x); Ny=numel(grid.y); Nz=numel(grid.z);
            k1 = 2*pi*freq/app.lensSpeedEdit.Value; k2 = 2*pi*freq/app.resinSpeedEdit.Value;
            lens_thk = 1e3*2*pi/abs(k1-k2); %[mm]
            phs_to_thk = 1e3*angle(recon)/(k1-k2)+lens_thk/2; % [mm], delta from trasducer surface, toward -DV direction
            idx_transducer_before = find(grid.z==transducer.DV);
            idx_transducer_after = idx_transducer_before + ceil((lens_thk)/vox)+1; % retreat transducer by lens thickness, holes on the resin without +1
            newSource = transducer;
            newSource.DV = grid.z(idx_transducer_after);
            newSource.model = (grid.X-newSource.ML).^2+(grid.Y-newSource.AP).^2<=newSource.r^2 & (grid.Z==grid.z(idx_transducer_after));
            % make lens from the transducer layer by layer
            proj = (grid.X-newSource.ML).^2+(grid.Y-newSource.AP).^2<=newSource.r^2;
            lens_model = zeros(Nx,Ny,Nz,'logical'); resin_model = zeros(Nx,Ny,Nz,'logical');
            iterZ0 = idx_transducer_after;
            iterZf = idx_transducer_before+1;
            iterZ = iterZ0;
            while iterZ >= iterZf
                layer = proj & (grid.Z==grid.z(iterZ));
                lens_model(find(layer)) = phs_to_thk>=vox*(iterZ0-iterZ);
                resin_model(find(layer)) = phs_to_thk<vox*(iterZ0-iterZ);
                iterZ = iterZ-1;
            end
        end
        
        function plotData(app,grid,view,sim,data,mask,fig_name)
            Nx = numel(grid.x); Ny = numel(grid.y); Nz = numel(grid.z);
            nx = numel(view.x); ny = numel(view.y); nz = numel(view.z);
            result.p_max=zeros(Nx,Ny,Nz,'single');
            result.p_max(sim.sensor)=data.p_max;
            result.I_est = 0.5*(result.p_max).^2./(sim.medium.sound_speed.*sim.medium.density); % I = p_rms^2/ρc (free space assumption)
            p_max=max(result.p_max.*view.regionOfInterest,[],'all');
            I_max=max(result.I_est.*view.regionOfInterest,[],'all');
            xIdx=find(grid.x==view.x(1)):find(grid.x==view.x(end));
            yIdx=find(grid.y==view.y(1)):find(grid.y==view.y(end));
            zIdx=find(grid.z==view.z(1)):find(grid.z==view.z(end));
            result.p_max=result.p_max(xIdx,yIdx,zIdx);
            result.I_est=result.I_est(xIdx,yIdx,zIdx);
            mask=mask(xIdx,yIdx,zIdx);
            switch sim.recordType
                case 'max' % plot p_max and I_sppa by estimation from p_max
                    plotN =2;
                case 'raw' % plot p_max, I_sppa by estimation, and exact I_sppa from I_spta obtained
                    plotN =3;
            end
            p_slice=cell(3,1); I_slice=cell(3,1); rgb_slice=cell(3,1); alpha_slice=cell(3,1); Isppa_slice=cell(3,1);
            for iterS=1:3
                if iterS==2 & ~app.target2Check.Value
                    continue
                elseif iterS==3 & ~app.addSliceCheck.Value
                    continue
                end
                p_slice{iterS}.yz = squeeze(result.p_max(view.x==view.slice{iterS}.ML,:,:));
                p_slice{iterS}.xz = squeeze(result.p_max(:,view.y==view.slice{iterS}.AP,:));
                p_slice{iterS}.xy = squeeze(result.p_max(:,:,view.z==view.slice{iterS}.DV));
                I_slice{iterS}.yz = squeeze(result.I_est(view.x==view.slice{iterS}.ML,:,:));
                I_slice{iterS}.xz = squeeze(result.I_est(:,view.y==view.slice{iterS}.AP,:));
                I_slice{iterS}.xy = squeeze(result.I_est(:,:,view.z==view.slice{iterS}.DV));
                m_rgb=cat(4,mask,mask,mask); m_alpha=mask~=0;
                rgb_slice{iterS}.yz = squeeze(m_rgb(view.x==view.slice{iterS}.ML,:,:,:));
                rgb_slice{iterS}.xz = squeeze(m_rgb(:,view.y==view.slice{iterS}.AP,:,:));
                rgb_slice{iterS}.xy = squeeze(m_rgb(:,:,view.z==view.slice{iterS}.DV,:));
                alpha_slice{iterS}.yz = squeeze(m_alpha(view.x==view.slice{iterS}.ML,:,:));
                alpha_slice{iterS}.xz = squeeze(m_alpha(:,view.y==view.slice{iterS}.AP,:));
                alpha_slice{iterS}.xy = squeeze(m_alpha(:,:,view.z==view.slice{iterS}.DV));
                figure; t=tiledlayout(plotN,3); t.TileSpacing='compact'; t.Padding='compact';
                title(t,'TR reconstruction');
                ax1=nexttile; imagesc(view.y, view.z, transpose(p_slice{iterS}.yz), [0 p_max]); ax1.YDir='normal'; colormap(ax1,app.colorbarDrop.Value); axis image; xlabel('AP [mm]'); ylabel('DV [mm]'); title(['max(p(t)) @ ML = ',num2str(view.slice{iterS}.ML)]); colorbar;
                hold on; h=imagesc(view.y,view.z,permute(rgb_slice{iterS}.yz,[2,1,3])); set(h, 'AlphaData', transpose(alpha_slice{iterS}.yz)); hold off;
                ax1=nexttile; imagesc(view.x, view.z, transpose(p_slice{iterS}.xz), [0 p_max]); ax1.YDir='normal'; colormap(ax1,app.colorbarDrop.Value); axis image; xlabel('ML [mm]'); ylabel('DV [mm]'); title(['max(p(t)) @ AP = ',num2str(view.slice{iterS}.AP)]); colorbar;
                hold on; h=imagesc(view.x,view.z,permute(rgb_slice{iterS}.xz,[2,1,3])); set(h, 'AlphaData', transpose(alpha_slice{iterS}.xz)); hold off;
                ax1=nexttile; imagesc(view.x, view.y, transpose(p_slice{iterS}.xy), [0 p_max]); ax1.YDir='normal'; colormap(ax1,app.colorbarDrop.Value); axis image; xlabel('ML [mm]'); ylabel('AP [mm]'); title(['max(p(t)) @ DV = ',num2str(view.slice{iterS}.DV)]); colorbar;
                hold on; h=imagesc(view.x,view.y,permute(rgb_slice{iterS}.xy,[2,1,3])); set(h, 'AlphaData', transpose(alpha_slice{iterS}.xy)); hold off;
                ax1=nexttile; imagesc(view.y, view.z, transpose(I_slice{iterS}.yz), [0 I_max]); ax1.YDir='normal'; colormap(ax1,app.colorbarDrop.Value); axis image; xlabel('AP [mm]'); ylabel('DV [mm]'); title(['Estimated I_{SPPA} @ ML = ',num2str(view.slice{iterS}.ML)]); colorbar; 
                hold on; h=imagesc(view.y,view.z,permute(rgb_slice{iterS}.yz,[2,1,3])); set(h, 'AlphaData', transpose(alpha_slice{iterS}.yz)); hold off;
                ax1=nexttile; imagesc(view.x, view.z, transpose(I_slice{iterS}.xz), [0 I_max]); ax1.YDir='normal'; colormap(ax1,app.colorbarDrop.Value); axis image; xlabel('ML [mm]'); ylabel('DV [mm]'); title(['Estimated I_{SPPA} @ AP = ',num2str(view.slice{iterS}.AP)]); colorbar;
                hold on; h=imagesc(view.x,view.z,permute(rgb_slice{iterS}.xz,[2,1,3])); set(h, 'AlphaData', transpose(alpha_slice{iterS}.xz)); hold off;
                ax1=nexttile; imagesc(view.x, view.y, transpose(I_slice{iterS}.xy), [0 I_max]); ax1.YDir='normal'; colormap(ax1,app.colorbarDrop.Value); axis image; xlabel('ML [mm]'); ylabel('AP [mm]'); title(['Estimated I_{SPPA} @ DV = ',num2str(view.slice{iterS}.DV)]); colorbar;
                hold on; h=imagesc(view.x,view.y,permute(rgb_slice{iterS}.xy,[2,1,3])); set(h, 'AlphaData', transpose(alpha_slice{iterS}.xy)); hold off;
                if strcmp(sim.recordType,'raw')
                    result.I_sppa = zeros(Nx,Ny,Nz); result.I_sppa(find(sim.sensor))=data.I_sppa;
                    Isppa_max=max(result.I_sppa.*view.regionOfInterest,[],'all');
                    result_I_sppa=result_I_sppa(xIdx,yIdx,zIdx);
                    Isppa_slice{iterS}.yz = squeeze(result.I_sppa(view.x==view.slice{iterS}.ML,:,:));
                    Isppa_slice{iterS}.xz = squeeze(result.I_sppa(:,view.y==view.slice{iterS}.AP,:));
                    Isppa_slice{iterS}.xy = squeeze(result.I_sppa(:,:,view.z==view.slice{iterS}.DV));
                    ax1=nexttile; imagesc(view.y, view.z, transpose(Isppa_slice{iterS}.yz), [0 Isppa_max]); ax1.YDir='normal'; colormap(ax1,app.colorbarDrop.Value); axis image; xlabel('AP [mm]'); ylabel('DV [mm]'); title(['I_{SPPA} @ ML = ',num2str(view.slice{iterS}.ML)]); colorbar; 
                    hold on; h=imagesc(view.y,view.z,permute(rgb_slice{iterS}.yz,[2,1,3])); set(h, 'AlphaData', transpose(alpha_slice{iterS}.yz)); hold off;
                    ax1=nexttile; imagesc(view.x, view.z, transpose(Isppa_slice{iterS}.xz), [0 Isppa_max]); ax1.YDir='normal'; colormap(ax1,app.colorbarDrop.Value); axis image; xlabel('ML [mm]'); ylabel('DV [mm]'); title(['I_{SPPA} @ AP = ',num2str(view.slice{iterS}.AP)]); colorbar;
                    hold on; h=imagesc(view.x,view.z,permute(rgb_slice{iterS}.xz,[2,1,3])); set(h, 'AlphaData', transpose(alpha_slice{iterS}.xz)); hold off;
                    ax1=nexttile; imagesc(view.x, view.y, transpose(Isppa_slice{iterS}.xy), [0 Isppa_max]); ax1.YDir='normal'; colormap(ax1,app.colorbarDrop.Value); axis image; xlabel('ML [mm]'); ylabel('AP [mm]'); title(['I_{SPPA} @ DV = ',num2str(view.slice{iterS}.DV)]); colorbar;
                    hold on; h=imagesc(view.x,view.y,permute(rgb_slice{iterS}.xy,[2,1,3])); set(h, 'AlphaData', transpose(alpha_slice{iterS}.xy)); hold off;
                end
                drawnow
                savefig([app.saveDirEdit.Value,app.fileDirEdit.Value,'\',fig_name]);
            end
        end % function
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Main function %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function simulate(app,grid,transducer,target,model,medium,view)
            freq = 1e6*app.frequencyEdit.Value; %[Hz]
            vox = app.voxelEdit.Value; %[mm]
            Nx = numel(grid.x); Ny = numel(grid.y); Nz = numel(grid.z); %[mm]
            if app.planeWaveCheck.Value %% skip the step1 for plane wave simulation
                model.resin = zeros(Nx,Ny,Nz);
            else %% Step 1. BUILD A TRANSDUCER
                %% i. Source at the target
                sim.source_model = target.model;
                sim.source_p = 1; % complex pressure
                sim.sensor = transducer.model;
                sim.recordType = 'raw'; % recording raw p and u for time reversal
                sim.source_BC = 'dirichlet';
                sim.filename_in = [app.saveDirEdit.Value, app.fileDirEdit.Value, '\step1_in.h5'];
                sim.filename_out = [app.saveDirEdit.Value, app.fileDirEdit.Value, '\step1_out.h5'];
                sim.medium = medium.step1{app.considerSkullCheck.Value+1}
                data = app.CPP_run(grid, sim);
                recon = conj(data.p); % conjugation of the recorded is used for excitation
                recon = 1*exp(i*angle(recon));% without modulation on amplitude
                recorded = zeros(numel(transducer.x),numel(transducer.y));
                excitation = zeros(numel(transducer.x),numel(transducer.y));
                recorded(find((transducer.X-transducer.ML).^2+(transducer.Y-transducer.AP).^2<=transducer.r^2))=data.p;
                excitation(find((transducer.X-transducer.ML).^2+(transducer.Y-transducer.AP).^2<=transducer.r^2))=recon;
                figure; tlo=tiledlayout(2,2); tlo.TileSpacing='compact'; tlo.Padding='compact';
                title(tlo,'Time reversal recording'); xlabel(tlo,'ML [mm]'); ylabel(tlo,'AP [mm]');
                ax1=nexttile; imagesc(transducer.x,transducer.y,transpose(abs(recorded))); ax1.YDir='normal'; axis image; colormap(ax1,'jet'); colorbar; title('Recorded amplitude');
                ax2=nexttile; imagesc(transducer.x,transducer.y,transpose(angle(recorded))); ax2.YDir='normal'; axis image; colormap(ax2,'jet'); colorbar; title('Recorded phase');
                ax3=nexttile; imagesc(transducer.x,transducer.y,transpose(abs(excitation))); ax3.YDir='normal'; axis image; colormap(ax3,'jet'); colorbar; title('Excitation amplitude');
                ax4=nexttile; imagesc(transducer.x,transducer.y,transpose(angle(excitation))); ax4.YDir='normal'; axis image; colormap(ax4,'jet'); colorbar; title('Excitation phase');
                drawnow
                savefig([app.saveDirEdit.Value, app.fileDirEdit.Value, '\Fig1_TRRecord.fig']);
                %% ii. Build a lens
                model.lens=zeros(Nx,Ny,Nz,'logical');
                model.resin=zeros(Nx,Ny,Nz,'logical');
                newSource=transducer;
                if app.lensCheck.Value
                    [model.lens,model.resin,newSource]=app.makeLens(grid,transducer,recon);
                end
                if any(model.resin,'all')
                    lens_filename=[app.saveDirEdit.Value,app.fileDirEdit.Value,'\lens.stl'];
                    compression=1; smooth=0;
                    voxelToStl(grid.x,grid.y,grid.z,model.resin,lens_filename,compression,smooth);
                end
                if any(model.collimator,'all')
                    coll_filename=[app.saveDirEdit.Value,app.fileDirEdit.Value,'\collimator.stl'];
                    compression=1; smooth=0;
                    voxelToStl(grid.x,grid.y,grid.z,model.collimator,coll_filename,compression,smooth);
                end
                if any(model.resin,'all')&any(model.collimator,'all')
                    merge_filename=[app.saveDirEdit.Value,app.fileDirEdit.Value,'\collimatedLens.stl'];
                    compression=1; smooth=0;
                    voxelToStl(grid.x,grid.y,grid.z,(model.resin+model.collimator),merge_filename,compression,smooth);
                end
                phase_output.phs=angle(excitation);
                phase_output.freq=freq;
                phase_output.dx=vox;
                phase_output.resinSpeed=app.resinSpeedEdit.Value;
                phase_output.lensSpeed=app.lensSpeedEdit.Value;
                phase_output.grid_x=transducer.x;
                phase_output.grid_y=transducer.y;
                phase_output.tdc_ML=transducer.ML;
                phase_output.tdc_AP=transducer.AP;
                phase_output.tdc_DV=transducer.DV;
                phase_output.tdc_r=transducer.r;
                phs_filename=[app.saveDirEdit.Value,app.fileDirEdit.Value,'\phase.mat'];
                save(phs_filename,'phase_output');
            end
            % Step 2. PROPAGATION FROM THE TRANSDUCER
            medium.step2=cell(2,1);
            for iterB=1:2
                if (iterB==1 & ~app.withoutSkullCheck.Value)
                    continue
                elseif (iterB==2 & ~app.withSkullCheck.Value)
                    continue
                end
                medium.step2{iterB}.sound_speed = app.mediumSpeedEdit.Value*ones(Nx,Ny,Nz,'single');
                medium.step2{iterB}.density = app.mediumDensityEdit.Value*ones(Nx,Ny,Nz,'single');
                medium.step2{iterB}.alpha_coeff = app.mediumAlphaEdit.Value*ones(Nx,Ny,Nz,'single'); % Study k-wave manual pp32-33.
                medium.step2{iterB}.alpha_power = app.mediumPowerEdit.Value;
                if iterB==2
                    medium.step2{iterB}.sound_speed(model.skull) = app.boneSpeedEdit.Value;
                    medium.step2{iterB}.density(model.skull) = app.boneDensityEdit.Value;
                    medium.step2{iterB}.alpha_coeff(model.skull) = app.boneAlphaEdit.Value;
                end
                medium.step2{iterB}.sound_speed(model.collimator) = app.resinSpeedEdit.Value;
                medium.step2{iterB}.density(model.collimator) = app.resinDensityEdit.Value;
                medium.step2{iterB}.alpha_coeff(model.collimator) = app.resinAlphaEdit.Value;
                medium.step2{iterB}.sound_speed(model.resin) = app.resinSpeedEdit.Value;
                medium.step2{iterB}.density(model.resin) = app.resinDensityEdit.Value;
                medium.step2{iterB}.alpha_coeff(model.resin) = app.resinAlphaEdit.Value;
                medium.step2{iterB}.sound_speed(model.lens) = app.lensSpeedEdit.Value;
                medium.step2{iterB}.density(model.lens) = app.lensDensityEdit.Value;
                medium.step2{iterB}.alpha_coeff(model.lens) = app.lensAlphaEdit.Value;
                if app.planeWaveCheck.Value
                    sim.source_model=transducer.model;
                    sim.source_p=1;
                    sim.source_BC='additive';
                elseif ~app.lensCheck.Value
                    sim.source_model=transducer.model;
                    sim.source_p=recon;
                    sim.source_BC = 'dirichlet'; % ideal PAT, thus dirichlet BC
                else
                    sim.source_model=newSource.model;
                    sim.source_p=1;
                    sim.source_BC = 'additive';
                end
                mask = 0.01*sim.source_model ...
                    +(app.boneRGBEdit.Value/255)*model.skull*(iterB-1) ...
                    +(app.lensRGBEdit.Value/255)*model.lens ...
                    +(app.resinRGBEdit.Value/255)*(model.collimator+model.resin);
                sim.filename_in = [app.saveDirEdit.Value, app.fileDirEdit.Value, '\step2-',num2str(iterB),'_in.h5'];
                sim.filename_out = [app.saveDirEdit.Value, app.fileDirEdit.Value, '\step2-',num2str(iterB),'_out.h5'];
                sim.sensor = view.slices;
                sim.medium = medium.step2{iterB};
                sim.recordType = view.mode;
                data = app.CPP_run(grid,sim);
                app.plotData(grid,view,sim,data,mask,['Fig2-',num2str(iterB),'TRReconstruction.fig']);
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% End %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function error_flag = checkError(app)
            error_flag = 0;
            if ~ isfile(app.phaseDirEdit.Value)
                warndlg('You must give a phase file.','Warning');
                error_flag = 1;
                return
            end
            clear('phase_output');
            load(app.phaseDirEdit.Value);
            if ~exist('phase_output')
                warndlg('You must load a phase data exported from tFUS Simulator','Warning');
                error_flag = 1;
                return
            end
            if ~app.zPixelEdit.Value
                warndlg('You must give a valid dz','Warning');
                error_flag = 1;
                return
            end
        end
        
        function generateLens(app)
            load(app.phaseDirEdit.Value);
            freq = phase_output.freq;
            v1 = phase_output.lensSpeed;
            v2 = phase_output.resinSpeed;
            dx = phase_output.dx;
            grid.x = phase_output.grid_x;
            grid.y = phase_output.grid_y;
            Nx = numel(grid.x);
            Ny = numel(grid.y);
            phs = phase_output.phs;
            tdc_ML = phase_output.tdc_ML;
            tdc_AP = phase_output.tdc_AP;
            tdc_DV = phase_output.tdc_DV;
            tdc_r = phase_output.tdc_r;
            dir_out = [app.outputDirEdit.Value, app.filenameEdit.Value, '.stl'];
            dz = app.zPixelEdit.Value;
            k1 = 2*pi*freq/v1;
            k2 = 2*pi*freq/v2;
            lens_thk = 1e3*2*pi/abs(k1-k2); %[mm]
            Nz = ceil(lens_thk/dz)+1;
            z0 = tdc_DV + dx/2;
            grid.z = [z0 : dz : z0+(Nz-1)*dz];
            [grid.Y, grid.X, grid.Z] = meshgrid(grid.y, grid.x, grid.z);
            phs_to_thk = 1e3*phs/(k1-k2)+lens_thk/2; %[mm]
            proj = (grid.X-tdc_ML).^2+(grid.Y-tdc_AP).^2<=tdc_r^2;
            phs_to_thk = phs_to_thk(find(squeeze(proj(:,:,1))));
            lens_model = zeros(Nx,Ny,Nz); resin_model = zeros(Nx,Ny,Nz);
            iterZ = Nz;
            while iterZ >= 1
                layer = proj & (grid.Z==grid.z(iterZ));
                lens_model(find(layer)) = phs_to_thk>=dz*(Nz-iterZ);
                resin_model(find(layer)) = phs_to_thk<dz*(Nz-iterZ);
                iterZ = iterZ-1;
            end
            voxelToStl(grid.x, grid.y, grid.z, resin_model, dir_out, 1, 0);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            absPath = fileparts(mfilename('fullpath'));
            cd(absPath);
            app.skullDirEdit.Value = [absPath '\models\mouseStereoS.stl'];
            app.saveDirEdit.Value = [absPath '\output\'];
            dk = abs(2*pi*(1e6*app.frequencyEdit.Value)/app.lensSpeedEdit.Value - 2*pi*(1e6*app.frequencyEdit.Value)/app.resinSpeedEdit.Value);
            app.lensValidity.Value = round(10*(360*app.voxelEdit.Value/(1e3*2*pi/dk)))/10; % [deg/vox]
            %% lens maker
            app.phaseDirEdit.Value = [absPath];
            app.outputDirEdit.Value = [absPath, '\'];
            defaultImage = imread('FIGEggplant.jpg');
            imagesc(app.UIAxes,defaultImage);
            axis(app.UIAxes,'tight');
            axis(app.UIAxes,'equal');
            drawnow
        end

        % Button pushed function: skullDirButton
        function skullDirButtonPushed(app, event)
            [file,path] = uigetfile([pwd,'\models\skull.stl'],'*.stl');
            if file
                app.skullDirEdit.Value = [path, file];
            end
            drawnow
        end

        % Button pushed function: binaryDirButton
        function binaryDirButtonPushed(app, event)
            dname = uigetdir(app.binaryDirEdit.Value);
            if dname
                app.binaryDirEdit.Value = dname;
            end
            drawnow
        end

        % Button pushed function: saveDirButton
        function saveDirButtonPushed(app, event)
            dname = uigetdir(app.saveDirEdit.Value);
            if dname
                app.saveDirEdit.Value = [dname, '\'];
            end
            drawnow
        end

        % Button pushed function: nameAutoButton
        function nameAutoButtonPushed(app, event)
            timeNow = clock;
            common_name = [num2str(timeNow(1)),num2str(timeNow(2)),num2str(timeNow(3)),'_',num2str(timeNow(4)),num2str(timeNow(5))];
            app.fileDirEdit.Value = common_name;
            drawnow
        end

        % Button pushed function: spaceAutoButton
        function spaceAutoButtonPushed(app, event)
            dx = app.xMaxEdit.Value-app.xMinEdit.Value;
            dy = app.yMaxEdit.Value-app.yMinEdit.Value;
            dz = app.zMaxEdit.Value-app.zMinEdit.Value;
            D = 1e-3*sqrt(dx^2+dy^2+dz^2);
            v = [app.mediumSpeedEdit.Value app.boneSpeedEdit.Value app.lensSpeedEdit.Value app.resinSpeedEdit.Value];
            v_min = min(v);
            v_max = max(v);
            T= 2*D/v_min + app.pulsesEdit.Value/(1e6*app.frequencyEdit.Value);
            app.timeEdit.Value=T;
            app.PMLEdit.Value=1e3*v_max/(1e6*app.frequencyEdit.Value); % [mm], PML as lambda
            drawnow
        end

        % Button pushed function: collectAutoButton
        function collectAutoButtonPushed(app, event)
            app.xViewMinEdit.Value = app.xMinEdit.Value;
            app.xViewMaxEdit.Value = app.xMaxEdit.Value;
            app.yViewMinEdit.Value = app.yMinEdit.Value;
            app.yViewMaxEdit.Value = app.yMaxEdit.Value;
            app.zViewMinEdit.Value = app.zMinEdit.Value;
            app.zViewMaxEdit.Value = app.zMaxEdit.Value;
            drawnow
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            [file,path] = uigetfile('*.mat',app.saveDirEdit.Value);
            if path
                app.loadState(file,path);
            end
        end

        % Value changed function: target2Check
        function target2CheckValueChanged(app, event)
            value = app.target2Check.Value;
            if value
                app.targetDV2Edit.Enable = 'on';
                app.targetAP2Edit.Enable = 'on';
                app.targetML2Edit.Enable = 'on';
            else
                app.targetDV2Edit.Enable = 'off';
                app.targetAP2Edit.Enable = 'off';
                app.targetML2Edit.Enable = 'off';
            end
            drawnow
        end

        % Value changed function: addSliceCheck
        function addSliceCheckValueChanged(app, event)
            value = app.addSliceCheck.Value;
            if value
                app.sliceDVEdit.Enable = 'on';
                app.sliceAPEdit.Enable = 'on';
                app.sliceMLEdit.Enable = 'on';
            else
                app.sliceDVEdit.Enable = 'off';
                app.sliceAPEdit.Enable = 'off';
                app.sliceMLEdit.Enable = 'off';
            end
            drawnow
        end

        % Value changed function: planeWaveCheck
        function planeWaveCheckValueChanged(app, event)
            if app.planeWaveCheck.Value
                app.TRrecordingLabel.Enable='off';
                app.BuildalensLabel.Enable='off';
            else
                app.TRrecordingLabel.Enable='on';
                app.BuildalensLabel.Enable='on';
            end
            drawnow
        end

        % Value changed function: formatDrop
        function formatDropValueChanged(app, event)
            value = app.formatDrop.Value;
            if strcmp(value,'p(t)')
                warndlg('p(t) simulation requires large disc space and RAM','Warning');
            end
        end

        % Value changed function: collimatorCheck
        function collimatorCheckValueChanged(app, event)
            value = app.collimatorCheck.Value;
            if value
                app.tipAPEdit.Enable = 'on';
                app.tipMLEdit.Enable = 'on';
                app.tipOREdit.Enable = 'on';
                app.tipIREdit.Enable = 'on';
                app.tipAPLabel.Enable = 'on';
                app.tipMLLabel.Enable = 'on';
                app.tipORLabel.Enable = 'on';
                app.tipIRLabel.Enable = 'on';
            else
                app.tipAPEdit.Enable = 'off';
                app.tipMLEdit.Enable = 'off';
                app.tipOREdit.Enable = 'off';
                app.tipIREdit.Enable = 'off';
                app.tipAPLabel.Enable = 'off';
                app.tipMLLabel.Enable = 'off';
                app.tipORLabel.Enable = 'off';
                app.tipIRLabel.Enable = 'off';
            end
            drawnow
        end

        % Value changed function: lensCheck
        function lensCheckValueChanged(app, event)
            value = app.lensCheck.Value;
            if value & app.lensValidity.Value>40
                msgbox('The lens will be inaccurate. Give a smaller voxel value, or skip the lens simulation and use the Lens Maker Add-on later.','Suggestion','help');
            end
            dk = abs(2*pi*(1e6*app.frequencyEdit.Value)/app.lensSpeedEdit.Value - 2*pi*(1e6*app.frequencyEdit.Value)/app.resinSpeedEdit.Value);
            app.lensValidity.Value = round(10*(360*app.voxelEdit.Value/(1e3*2*pi/dk)))/10; % [deg/vox]
            drawnow
        end

        % Value changed function: mediumPowerEdit
        function mediumPowerEditValueChanged(app, event)
            if app.mediumPowerEdit.Value==1
                warndlg('Alpha power must not be 1 in k-Wave simuation. Use a value very close to 1 instead.','Warning');
                app.mediumPowerEdit.Value = 1.05;
            end
            drawnow
        end

        % Value changed function: frequencyEdit
        function frequencyEditValueChanged(app, event)
            value = app.frequencyEdit.Value;
            dk = abs(2*pi*(1e6*app.frequencyEdit.Value)/app.lensSpeedEdit.Value - 2*pi*(1e6*app.frequencyEdit.Value)/app.resinSpeedEdit.Value);
            app.lensValidity.Value = round(10*(360*app.voxelEdit.Value/(1e3*2*pi/dk)))/10; % [deg/vox]
            drawnow
        end

        % Value changed function: voxelEdit
        function voxelEditValueChanged(app, event)
            dk = abs(2*pi*(1e6*app.frequencyEdit.Value)/app.lensSpeedEdit.Value - 2*pi*(1e6*app.frequencyEdit.Value)/app.resinSpeedEdit.Value);
            app.lensValidity.Value = round(10*(360*app.voxelEdit.Value/(1e3*2*pi/dk)))/10; % [deg/vox]
            drawnow
        end

        % Value changed function: resinSpeedEdit
        function resinSpeedEditValueChanged(app, event)
            dk = abs(2*pi*(1e6*app.frequencyEdit.Value)/app.lensSpeedEdit.Value - 2*pi*(1e6*app.frequencyEdit.Value)/app.resinSpeedEdit.Value);
            app.lensValidity.Value = round(10*(360*app.voxelEdit.Value/(1e3*2*pi/dk)))/10; % [deg/vox]
            drawnow
        end

        % Value changed function: lensSpeedEdit
        function lensSpeedEditValueChanged(app, event)
            dk = abs(2*pi*(1e6*app.frequencyEdit.Value)/app.lensSpeedEdit.Value - 2*pi*(1e6*app.frequencyEdit.Value)/app.resinSpeedEdit.Value);
            app.lensValidity.Value = round(10*(360*app.voxelEdit.Value/(1e3*2*pi/dk)))/10; % [deg/vox]
            drawnow
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            error_flag = app.checkInput();
            if(error_flag)
                return
            end
            fileDir=[app.saveDirEdit.Value, app.fileDirEdit.Value];
            if isfolder(fileDir)
                fig = uifigure;
                ui_msg = 'The file already exists. Do you want to recall/overwrite?';
                ui_title = 'Confirm recall/overwrite';
                selection = uiconfirm(fig,ui_msg,ui_title,...
                    'Options',{'Yes','No'}, ...
                    'DefaultOption',1,'CancelOption',2);
                switch selection
                    case 'Yes'
                        close(fig);
                        confirm_flag=1;
                    case 'No'
                        close(fig);
                        confirm_flag=0;
                end
                if (~confirm_flag)
                    return
                end
            end
            [grid,transducer,target,model,medium,view,error_flag] = app.buildSimulationInput();
            if(error_flag)
                return
            end
            confirm_flag = app.confirm(grid,transducer,target,model,medium,view);
            if(~confirm_flag)
                return
            end
            if ~isfolder(fileDir)
                mkdir(fileDir);
            end
            state_filename = [app.saveDirEdit.Value, app.fileDirEdit.Value, '\state.mat'];
            app.saveState(state_filename);
            if app.stlToVoxelAButton.Value
                save([app.saveDirEdit.Value,app.fileDirEdit.Value,'\voxelizedSkullA.mat'],'grid','model');
            else
                save([app.saveDirEdit.Value,app.fileDirEdit.Value,'\voxelizedSkullB.mat'],'grid','model');
            end
            app.simulate(grid,transducer,target,model,medium,view);
            fig = uifigure;
            message = {'Simulation completed.','Congratulations!'};
            uialert(fig,message,'Hey~','Icon','FIGEggplant.jpg');
        end

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if path
                clear('phase_output');
                load([path, file]);
                if ~exist('phase_output')
                    warndlg('You must load a phase data exported from tFUS Simulator','Warning');
                    return
                end
                app.phaseDirEdit.Value = [path, file];
                app.importedFreq.Value = phase_output.freq*1e-6;
                app.importedVoxel.Value = phase_output.dx;
                app.importedResinSpeed.Value = phase_output.resinSpeed;
                app.importedLensSpeed.Value = phase_output.lensSpeed;
                grid_x = phase_output.grid_x;
                grid_y = phase_output.grid_y;
                imagesc(app.UIAxes,grid_x,grid_y,transpose(phase_output.phs));
                app.UIAxes.YDir='normal';
                axis(app.UIAxes,'tight');
                axis(app.UIAxes,'equal');
                colormap(app.UIAxes,'jet');
                colorbar(app.UIAxes);
                app.outputDirEdit.Value = path;
                app.filenameEdit.Value = [file(1:end-4), '_HQlens'];
                clear('phase_output');
            end
        end

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
            if app.zPixelEdit.Value & app.importedFreq.Value & app.importedLensSpeed.Value & app.importedResinSpeed.Value;
                if app.importedResinSpeed.Value == app.importedLensSpeed.Value
                    warndlg('Resin and lens speed must be different','Warning');
                    return
                end
                pix = app.zPixelEdit.Value;
                freq = 1e6*app.importedFreq.Value;
                k1 = 2*pi*freq/app.importedLensSpeed.Value;
                k2 = 2*pi*freq/app.importedResinSpeed.Value;
                lens_thk = 1e3*2*pi/abs(k1-k2); % [mm]
                app.totalLensThk.Value = ceil(lens_thk/pix)*pix;
                dpv = 360*pix/lens_thk; % [deg/vox]
                dpv = round(100*dpv)/100;
                app.voxelValidity.Value = dpv;
                if dpv>40
                    msgbox('The lens will be inaccurate. Give a smaller dz.','Suggestion','help');
                end
            else
                warndlg('Please give all necessary parameters','Warning')
            end
        end

        % Button pushed function: outDirButton
        function outDirButtonPushed(app, event)
            dname = uigetdir(app.outputDirEdit.Value);
            if dname
                app.outputDirEdit.Value = [dname, '\'];
            end
        end

        % Button pushed function: generateButton
        function generateButtonPushed(app, event)
            error_flag = app.checkError();
            if error_flag
                return
            end
            app.progressLabel.Text = 'Converting to an stl file...'; drawnow
            app.generateLens();
            app.progressLabel.Text = 'Ready'; drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BMMtFUSSimulatorUIFigure = uifigure('Visible', 'off');
            app.BMMtFUSSimulatorUIFigure.Name = 'BMM tFUS Simulator';
            app.AcousticsPanel = uipanel(app.BMMtFUSSimulatorUIFigure);
            app.AcousticsPanel.Title = '1. Acoustics';
            app.frequencyEdit = uieditfield(app.AcousticsPanel, 'numeric');
            app.frequencyEdit.Limits = [0 Inf];
            app.frequencyEdit.ValueChangedFcn = createCallbackFcn(app, @frequencyEditValueChanged, true);
            app.frequencyEdit.Value = 2;
            app.voxelEdit = uieditfield(app.AcousticsPanel, 'numeric');
            app.voxelEdit.Limits = [0 Inf];
            app.voxelEdit.ValueChangedFcn = createCallbackFcn(app, @voxelEditValueChanged, true);
            app.voxelEdit.Value = 0.2;
            app.pulsesEdit = uieditfield(app.AcousticsPanel, 'numeric');
            app.pulsesEdit.Limits = [0 Inf];
            app.pulsesEdit.RoundFractionalValues = 'on';
            app.pulsesEdit.Value = 20;
            app.FreqMHzLabel_2 = uilabel(app.AcousticsPanel);
            app.FreqMHzLabel_2.Text = 'Freq. [MHz]';
            app.VoxelmmLabel_2 = uilabel(app.AcousticsPanel);
            app.VoxelmmLabel_2.Text = 'Voxel [mm]';
            app.ofpulsesLabel_2 = uilabel(app.AcousticsPanel);
            app.ofpulsesLabel_2.Text = '# of pulses';
            app.TargetPanel = uipanel(app.BMMtFUSSimulatorUIFigure);
            app.TargetPanel.Title = '5. Target';
            app.targetDV1Edit = uieditfield(app.TargetPanel, 'numeric');
            app.targetDV1Edit.Value = -3;
            app.targetAP1Edit = uieditfield(app.TargetPanel, 'numeric');
            app.targetML1Edit = uieditfield(app.TargetPanel, 'numeric');
            app.DVLabel_2 = uilabel(app.TargetPanel);
            app.DVLabel_2.Text = 'DV';
            app.APLabel_2 = uilabel(app.TargetPanel);
            app.APLabel_2.Text = 'AP';
            app.MLLabel_2 = uilabel(app.TargetPanel);
            app.MLLabel_2.Text = 'ML';
            app.targetDV2Edit = uieditfield(app.TargetPanel, 'numeric');
            app.targetDV2Edit.Enable = 'off';
            app.targetAP2Edit = uieditfield(app.TargetPanel, 'numeric');
            app.targetAP2Edit.Enable = 'off';
            app.targetML2Edit = uieditfield(app.TargetPanel, 'numeric');
            app.targetML2Edit.Enable = 'off';
            app.target2Check = uicheckbox(app.TargetPanel);
            app.target2Check.ValueChangedFcn = createCallbackFcn(app, @target2CheckValueChanged, true);
            app.target2Check.Text = '2nd';
            app.stLabel = uilabel(app.TargetPanel);
            app.stLabel.Text = '1st';
            app.TransducerPanel = uipanel(app.BMMtFUSSimulatorUIFigure);
            app.TransducerPanel.Title = '6. Transducer';
            app.transducerDVEdit = uieditfield(app.TransducerPanel, 'numeric');
            app.transducerDVEdit.Value = 1;
            app.transducerAPEdit = uieditfield(app.TransducerPanel, 'numeric');
            app.transducerMLEdit = uieditfield(app.TransducerPanel, 'numeric');
            app.DVLabel_3 = uilabel(app.TransducerPanel);
            app.DVLabel_3.Text = 'DV';
            app.APLabel_3 = uilabel(app.TransducerPanel);
            app.APLabel_3.Text = 'AP';
            app.MLLabel_3 = uilabel(app.TransducerPanel);
            app.MLLabel_3.Text = 'ML';
            app.transducerREdit = uieditfield(app.TransducerPanel, 'numeric');
            app.transducerREdit.Limits = [0 Inf];
            app.transducerREdit.Tooltip = {'For a square transducer, half of the side length'};
            app.transducerREdit.Value = 4;
            app.RmmLabel = uilabel(app.TransducerPanel);
            app.RmmLabel.Text = 'R [mm]';
            app.Skullbregmaat000Panel = uipanel(app.BMMtFUSSimulatorUIFigure);
            app.Skullbregmaat000Panel.Title = '2. Skull (bregma at 0,0,0)';
            app.skullDirButton = uibutton(app.Skullbregmaat000Panel, 'push');
            app.skullDirButton.ButtonPushedFcn = createCallbackFcn(app, @skullDirButtonPushed, true);
            app.skullDirButton.Text = 'STL';
            app.skullDirEdit = uieditfield(app.Skullbregmaat000Panel, 'text');
            app.CTCheck = uicheckbox(app.Skullbregmaat000Panel);
            app.CTCheck.Enable = 'off';
            app.CTCheck.Text = 'Use CT data (N/A)';
            app.skullCutEdit = uieditfield(app.Skullbregmaat000Panel, 'numeric');
            app.skullCutEdit.Tooltip = {'Cut skull at the DV of the input value. (0: full skull)'};
            app.CutskullatDVLabel = uilabel(app.Skullbregmaat000Panel);
            app.CutskullatDVLabel.Text = 'Cut skull at DV =';
            app.ByGKookLabel = uilabel(app.BMMtFUSSimulatorUIFigure);
            app.ByGKookLabel.Text = 'By G. Kook';
            app.binaryDirButton = uibutton(app.BMMtFUSSimulatorUIFigure, 'push');
            app.binaryDirButton.ButtonPushedFcn = createCallbackFcn(app, @binaryDirButtonPushed, true);
            app.binaryDirButton.Text = 'k-Wave Binary';
            app.binaryDirEdit = uieditfield(app.BMMtFUSSimulatorUIFigure, 'text');
            app.binaryDirEdit.Value = 'C:\Program Files\MATLAB\R2019b\toolbox\k-Wave\binaries';
            app.BMMtFUSSimulatorver11Label = uilabel(app.BMMtFUSSimulatorUIFigure);
            app.BMMtFUSSimulatorver11Label.Text = 'BMM tFUS Simulator ver1.1';
            app.resourceDrop = uidropdown(app.BMMtFUSSimulatorUIFigure);
            app.resourceDrop.Items = {'OMP', 'CUDA'};
            app.resourceDrop.Value = 'CUDA';
            app.runButton = uibutton(app.BMMtFUSSimulatorUIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'RUN';
            app.step1Panel = uipanel(app.BMMtFUSSimulatorUIFigure);
            app.step1Panel.Title = 'Simulation';
            app.considerSkullCheck = uicheckbox(app.step1Panel);
            app.considerSkullCheck.Text = 'Consider skull';
            app.considerSkullCheck.Value = true;
            app.TRrecordingLabel = uilabel(app.step1Panel);
            app.TRrecordingLabel.Text = '1. TR recording';
            app.BuildalensLabel = uilabel(app.step1Panel);
            app.BuildalensLabel.Text = '2. Build a lens';
            app.fig03 = uiimage(app.step1Panel);
            app.fig03.ImageSource = 'FIGURE04.png';
            app.lensCheck = uicheckbox(app.step1Panel);
            app.lensCheck.ValueChangedFcn = createCallbackFcn(app, @lensCheckValueChanged, true);
            app.lensCheck.Tooltip = {'Otherwise, ideal PAT'};
            app.lensCheck.Text = '';
            app.ValiditydegvxLabel = uilabel(app.step1Panel);
            app.ValiditydegvxLabel.Text = 'Validity [deg/vx]';
            app.lensValidity = uieditfield(app.step1Panel, 'numeric');
            app.lensValidity.Limits = [0 Inf];
            app.lensValidity.Editable = 'off';
            app.lensValidity.Tooltip = {''};
            app.fig02 = uiimage(app.step1Panel);
            app.fig02.ImageSource = 'FIGURE03.png';
            app.fig04 = uiimage(app.step1Panel);
            app.fig04.ImageSource = 'FIGURE05.png';
            app.withoutSkullCheck = uicheckbox(app.step1Panel);
            app.withoutSkullCheck.Text = '2-1. No skull';
            app.withSkullCheck = uicheckbox(app.step1Panel);
            app.withSkullCheck.Text = '2-2. With skull';
            app.withSkullCheck.Value = true;
            app.ReconstructLabel = uilabel(app.step1Panel);
            app.ReconstructLabel.Text = '3. Reconstruct';
            app.planeWaveCheck = uicheckbox(app.step1Panel);
            app.planeWaveCheck.ValueChangedFcn = createCallbackFcn(app, @planeWaveCheckValueChanged, true);
            app.planeWaveCheck.Text = 'Plane wave (skip 1 and 2)';
            app.SimulationspacePanel = uipanel(app.BMMtFUSSimulatorUIFigure);
            app.SimulationspacePanel.Title = '3. Simulation space';
            app.spaceAutoButton = uibutton(app.SimulationspacePanel, 'push');
            app.spaceAutoButton.ButtonPushedFcn = createCallbackFcn(app, @spaceAutoButtonPushed, true);
            app.spaceAutoButton.Text = 'Auto';
            app.timeEdit = uieditfield(app.SimulationspacePanel, 'numeric');
            app.timeEdit.Limits = [0 Inf];
            app.timeEdit.Tooltip = {'0: Automatically generate using an internal rule by k-wave'};
            app.PMLEdit = uieditfield(app.SimulationspacePanel, 'numeric');
            app.PMLEdit.Limits = [0 Inf];
            app.PMLEdit.Tooltip = {'Additional to simulation volume'};
            app.PMLEdit.Value = 0.75;
            app.xMinEdit = uieditfield(app.SimulationspacePanel, 'numeric');
            app.xMinEdit.Value = -8;
            app.xMaxEdit = uieditfield(app.SimulationspacePanel, 'numeric');
            app.xMaxEdit.Value = 8;
            app.yMinEdit = uieditfield(app.SimulationspacePanel, 'numeric');
            app.yMinEdit.Value = -9;
            app.yMaxEdit = uieditfield(app.SimulationspacePanel, 'numeric');
            app.yMaxEdit.Value = 8;
            app.zMinEdit = uieditfield(app.SimulationspacePanel, 'numeric');
            app.zMinEdit.Value = -12;
            app.zMaxEdit = uieditfield(app.SimulationspacePanel, 'numeric');
            app.zMaxEdit.Value = 5;
            app.TimesLabel = uilabel(app.SimulationspacePanel);
            app.TimesLabel.Text = 'Time [s]';
            app.PMLmmLabel = uilabel(app.SimulationspacePanel);
            app.PMLmmLabel.Text = 'PML [mm]';
            app.Label_7 = uilabel(app.SimulationspacePanel);
            app.Label_7.Text = '~';
            app.Label_8 = uilabel(app.SimulationspacePanel);
            app.Label_8.Text = '~';
            app.Label_9 = uilabel(app.SimulationspacePanel);
            app.Label_9.Text = '~';
            app.xmmLabel_4 = uilabel(app.SimulationspacePanel);
            app.xmmLabel_4.Text = 'x [mm]';
            app.ymmLabel_4 = uilabel(app.SimulationspacePanel);
            app.ymmLabel_4.Text = 'y [mm]';
            app.zmmLabel_4 = uilabel(app.SimulationspacePanel);
            app.zmmLabel_4.Text = 'z [mm]';
            app.MediumpropertyPanel = uipanel(app.BMMtFUSSimulatorUIFigure);
            app.MediumpropertyPanel.Title = '4. Medium property';
            app.mediumSpeedEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.mediumSpeedEdit.Limits = [0 Inf];
            app.mediumSpeedEdit.Value = 1500;
            app.mediumDensityEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.mediumDensityEdit.Limits = [0 Inf];
            app.mediumDensityEdit.Value = 1000;
            app.mediumAlphaEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.mediumAlphaEdit.Limits = [0 Inf];
            app.mediumAlphaEdit.Value = 0.0022;
            app.MediumLabel = uilabel(app.MediumpropertyPanel);
            app.MediumLabel.Text = 'Medium';
            app.boneSpeedEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.boneSpeedEdit.Limits = [0 Inf];
            app.boneSpeedEdit.Value = 3360;
            app.boneDensityEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.boneDensityEdit.Limits = [0 Inf];
            app.boneDensityEdit.Value = 1600;
            app.boneAlphaEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.boneAlphaEdit.Limits = [0 Inf];
            app.boneAlphaEdit.Value = 2.36;
            app.BoneLabel = uilabel(app.MediumpropertyPanel);
            app.BoneLabel.Text = 'Bone';
            app.LensLabel = uilabel(app.MediumpropertyPanel);
            app.LensLabel.Text = 'Lens';
            app.ResinLabel = uilabel(app.MediumpropertyPanel);
            app.ResinLabel.Text = 'Resin';
            app.lensSpeedEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.lensSpeedEdit.Limits = [0 Inf];
            app.lensSpeedEdit.ValueChangedFcn = createCallbackFcn(app, @lensSpeedEditValueChanged, true);
            app.lensSpeedEdit.Value = 1050;
            app.lensDensityEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.lensDensityEdit.Limits = [0 Inf];
            app.lensDensityEdit.Value = 965;
            app.lensAlphaEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.lensAlphaEdit.Limits = [0 Inf];
            app.lensAlphaEdit.Value = 3.316;
            app.resinSpeedEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.resinSpeedEdit.Limits = [0 Inf];
            app.resinSpeedEdit.ValueChangedFcn = createCallbackFcn(app, @resinSpeedEditValueChanged, true);
            app.resinSpeedEdit.Value = 2586;
            app.resinDensityEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.resinDensityEdit.Limits = [0 Inf];
            app.resinDensityEdit.Value = 1170;
            app.resinAlphaEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.resinAlphaEdit.Limits = [0 Inf];
            app.resinAlphaEdit.Value = 0.0022;
            app.mediumPowerEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.mediumPowerEdit.Limits = [0 Inf];
            app.mediumPowerEdit.ValueChangedFcn = createCallbackFcn(app, @mediumPowerEditValueChanged, true);
            app.mediumPowerEdit.Value = 1.05;
            app.fig01 = uiimage(app.MediumpropertyPanel);
            app.fig01.ImageSource = 'FIGURE01.png';
            app.PlotcolorRGBLabel = uilabel(app.MediumpropertyPanel);
            app.PlotcolorRGBLabel.Text = 'Plot color (R=G=B=)';
            app.boneRGBEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.boneRGBEdit.Limits = [0 255];
            app.boneRGBEdit.RoundFractionalValues = 'on';
            app.boneRGBEdit.Value = 25;
            app.resinRGBEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.resinRGBEdit.Limits = [0 255];
            app.resinRGBEdit.RoundFractionalValues = 'on';
            app.resinRGBEdit.Value = 80;
            app.lensRGBEdit = uieditfield(app.MediumpropertyPanel, 'numeric');
            app.lensRGBEdit.Limits = [0 255];
            app.lensRGBEdit.RoundFractionalValues = 'on';
            app.lensRGBEdit.Value = 180;
            app.cmsLabel = uilabel(app.MediumpropertyPanel);
            app.cmsLabel.Text = 'c [m/s]';
            app.kgm3Label = uilabel(app.MediumpropertyPanel);
            app.kgm3Label.Text = 'ρ [kg/m3]';
            app.dBMHzcmLabel_2 = uilabel(app.MediumpropertyPanel);
            app.dBMHzcmLabel_2.Text = {'α'; '[dB/MHz·cm]'};
            app.AlphapowerLabel = uilabel(app.MediumpropertyPanel);
            app.AlphapowerLabel.Text = 'Alpha power';
            app.DVisoppositetoconventionthusisinthebrainLabel = uilabel(app.BMMtFUSSimulatorUIFigure);
            app.DVisoppositetoconventionthusisinthebrainLabel.Text = '* DV is opposite to convention (thus, - is in the brain)';
            app.DataformatPanel = uipanel(app.BMMtFUSSimulatorUIFigure);
            app.DataformatPanel.Title = '9. Data format';
            app.saveDirButton = uibutton(app.DataformatPanel, 'push');
            app.saveDirButton.ButtonPushedFcn = createCallbackFcn(app, @saveDirButtonPushed, true);
            app.saveDirButton.Text = 'Save DIR';
            app.saveDirEdit = uieditfield(app.DataformatPanel, 'text');
            app.nameAutoButton = uibutton(app.DataformatPanel, 'push');
            app.nameAutoButton.ButtonPushedFcn = createCallbackFcn(app, @nameAutoButtonPushed, true);
            app.nameAutoButton.Text = 'Auto';
            app.fileDirEdit = uieditfield(app.DataformatPanel, 'text');
            app.Label_11 = uilabel(app.DataformatPanel);
            app.Label_11.Text = '\';
            app.formatDrop = uidropdown(app.DataformatPanel);
            app.formatDrop.Items = {'p(t)', 'max(p(t))'};
            app.formatDrop.ValueChangedFcn = createCallbackFcn(app, @formatDropValueChanged, true);
            app.formatDrop.Tooltip = {'p(t): Raw time signals are collected, large file size, more data'; 'max(p(t)): Only maximum values of p(t) is collected. small file size, only pRMS is estimated'};
            app.formatDrop.Value = 'max(p(t))';
            app.colorbarDrop = uidropdown(app.DataformatPanel);
            app.colorbarDrop.Items = {'jet', 'hot'};
            app.colorbarDrop.Value = 'jet';
            app.FormatLabel = uilabel(app.DataformatPanel);
            app.FormatLabel.Text = 'Format';
            app.DvolumetricscancautionheavyNACheckBox = uicheckbox(app.DataformatPanel);
            app.DvolumetricscancautionheavyNACheckBox.Enable = 'off';
            app.DvolumetricscancautionheavyNACheckBox.Text = '3D volumetric scan (caution: heavy) (N/A)';
            app.addSliceCheck = uicheckbox(app.DataformatPanel);
            app.addSliceCheck.ValueChangedFcn = createCallbackFcn(app, @addSliceCheckValueChanged, true);
            app.addSliceCheck.Tooltip = {''};
            app.addSliceCheck.Text = 'Add a slice crossing:';
            app.sliceDVEdit = uieditfield(app.DataformatPanel, 'numeric');
            app.sliceDVEdit.Enable = 'off';
            app.sliceAPEdit = uieditfield(app.DataformatPanel, 'numeric');
            app.sliceAPEdit.Enable = 'off';
            app.sliceMLEdit = uieditfield(app.DataformatPanel, 'numeric');
            app.sliceMLEdit.Enable = 'off';
            app.DVLabel_4 = uilabel(app.DataformatPanel);
            app.DVLabel_4.Text = 'DV';
            app.APLabel_4 = uilabel(app.DataformatPanel);
            app.APLabel_4.Text = 'AP';
            app.MLLabel_4 = uilabel(app.DataformatPanel);
            app.MLLabel_4.Text = 'ML';
            app.ColormapLabel = uilabel(app.DataformatPanel);
            app.ColormapLabel.Text = 'Colormap';
            app.loadButton = uibutton(app.BMMtFUSSimulatorUIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'Load';
            app.ButtonGroup = uibuttongroup(app.BMMtFUSSimulatorUIFigure);
            app.stlToVoxelAButton = uiradiobutton(app.ButtonGroup);
            app.stlToVoxelAButton.Text = 'stlToVoxelA';
            app.stlToVoxelAButton.Value = true;
            app.stlToVoxelBButton = uiradiobutton(app.ButtonGroup);
            app.stlToVoxelBButton.Text = 'stlToVoxelB';
            app.LensMakerAddonLabel = uilabel(app.BMMtFUSSimulatorUIFigure);
            app.LensMakerAddonLabel.Text = 'Lens Maker Add-on';
            app.ImportphasePanel = uipanel(app.BMMtFUSSimulatorUIFigure);
            app.ImportphasePanel.Title = '1. Import phase';
            app.importButton = uibutton(app.ImportphasePanel, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Text = 'Import';
            app.phaseDirEdit = uieditfield(app.ImportphasePanel, 'text');
            app.importedFreq = uieditfield(app.ImportphasePanel, 'numeric');
            app.importedFreq.Limits = [0 Inf];
            app.importedFreq.Editable = 'off';
            app.importedVoxel = uieditfield(app.ImportphasePanel, 'numeric');
            app.importedVoxel.Limits = [0 Inf];
            app.importedVoxel.Editable = 'off';
            app.FrequencyMHzLabel = uilabel(app.ImportphasePanel);
            app.FrequencyMHzLabel.Text = 'Frequency [MHz]';
            app.dxdymmLabel = uilabel(app.ImportphasePanel);
            app.dxdymmLabel.Text = 'dx, dy [mm]';
            app.importedResinSpeed = uieditfield(app.ImportphasePanel, 'numeric');
            app.importedResinSpeed.Limits = [0 Inf];
            app.ResinsoundspeedmsLabel = uilabel(app.ImportphasePanel);
            app.ResinsoundspeedmsLabel.Text = 'Resin sound speed [m/s]';
            app.importedLensSpeed = uieditfield(app.ImportphasePanel, 'numeric');
            app.importedLensSpeed.Limits = [0 Inf];
            app.LenssoundspeedmsLabel = uilabel(app.ImportphasePanel);
            app.LenssoundspeedmsLabel.Text = 'Lens sound speed [m/s]';
            app.UIAxes = uiaxes(app.ImportphasePanel);
            title(app.UIAxes, 'Imported phase')
            xlabel(app.UIAxes, 'ML [mm]')
            ylabel(app.UIAxes, 'AP [mm]')
            app.ExportlensPanel = uipanel(app.BMMtFUSSimulatorUIFigure);
            app.ExportlensPanel.Title = '2. Export lens';
            app.dzmmLabel = uilabel(app.ExportlensPanel);
            app.dzmmLabel.Text = 'dz [mm]';
            app.VoxelvaliditydegpxLabel = uilabel(app.ExportlensPanel);
            app.VoxelvaliditydegpxLabel.Text = 'Voxel validity [deg/px]';
            app.voxelValidity = uieditfield(app.ExportlensPanel, 'numeric');
            app.voxelValidity.Editable = 'off';
            app.calculateButton = uibutton(app.ExportlensPanel, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'Calculate';
            app.TotallensthicknessmmLabel = uilabel(app.ExportlensPanel);
            app.TotallensthicknessmmLabel.Text = 'Total lens thickness [mm]';
            app.totalLensThk = uieditfield(app.ExportlensPanel, 'numeric');
            app.totalLensThk.Limits = [0 Inf];
            app.totalLensThk.Editable = 'off';
            app.zPixelEdit = uieditfield(app.ExportlensPanel, 'numeric');
            app.zPixelEdit.Limits = [0 Inf];
            app.generateButton = uibutton(app.BMMtFUSSimulatorUIFigure, 'push');
            app.generateButton.ButtonPushedFcn = createCallbackFcn(app, @generateButtonPushed, true);
            app.generateButton.Text = 'GENERATE';
            app.outDirButton = uibutton(app.BMMtFUSSimulatorUIFigure, 'push');
            app.outDirButton.ButtonPushedFcn = createCallbackFcn(app, @outDirButtonPushed, true);
            app.outDirButton.Text = 'Output DIR';
            app.outputDirEdit = uieditfield(app.BMMtFUSSimulatorUIFigure, 'text');
            app.filenameEdit = uieditfield(app.BMMtFUSSimulatorUIFigure, 'text');
            app.FileNameLabel_2 = uilabel(app.BMMtFUSSimulatorUIFigure);
            app.FileNameLabel_2.Text = 'FileName';
            app.stlLabel = uilabel(app.BMMtFUSSimulatorUIFigure);
            app.stlLabel.Text = '.stl';
            app.Label_10 = uilabel(app.BMMtFUSSimulatorUIFigure);
            app.Label_10.Text = '______________________________________________________________________________________________________________________________';
            app.CollimatorPanel = uipanel(app.BMMtFUSSimulatorUIFigure);
            app.CollimatorPanel.Title = '7. Collimator';
            app.tipMLEdit = uieditfield(app.CollimatorPanel, 'numeric');
            app.tipAPEdit = uieditfield(app.CollimatorPanel, 'numeric');
            app.tipOREdit = uieditfield(app.CollimatorPanel, 'numeric');
            app.tipOREdit.Limits = [0 Inf];
            app.tipOREdit.Value = 2;
            app.tipORLabel = uilabel(app.CollimatorPanel);
            app.tipORLabel.Text = 'OR [mm]';
            app.tipMLLabel = uilabel(app.CollimatorPanel);
            app.tipMLLabel.Text = 'ML';
            app.tipAPLabel = uilabel(app.CollimatorPanel);
            app.tipAPLabel.Text = 'AP';
            app.tipIRLabel = uilabel(app.CollimatorPanel);
            app.tipIRLabel.Text = 'IR [mm]';
            app.Image2 = uiimage(app.CollimatorPanel);
            app.Image2.ImageSource = 'FIGURE02.png';
            app.tipIREdit = uieditfield(app.CollimatorPanel, 'numeric');
            app.tipIREdit.Limits = [0 Inf];
            app.tipIREdit.Tooltip = {'If 0, the collimator will be filled with resin'};
            app.BasethicknessmmLabel = uilabel(app.CollimatorPanel);
            app.BasethicknessmmLabel.Text = 'Base thickness [mm] ';
            app.resinBaseThkEdit = uieditfield(app.CollimatorPanel, 'numeric');
            app.resinBaseThkEdit.Limits = [0 Inf];
            app.resinBaseThkEdit.Tooltip = {'When resin model is super thin, it is not printable. Give a value to make it printable.'};
            app.resinBaseThkEdit.Value = 1;
            app.collimatorCheck = uicheckbox(app.CollimatorPanel);
            app.collimatorCheck.ValueChangedFcn = createCallbackFcn(app, @collimatorCheckValueChanged, true);
            app.collimatorCheck.Text = 'Collimated';
            app.collimatorCheck.Value = true;
            app.ResourceLabel = uilabel(app.BMMtFUSSimulatorUIFigure);
            app.ResourceLabel.Text = 'Resource';
            app.stlreadfnLabel = uilabel(app.BMMtFUSSimulatorUIFigure);
            app.stlreadfnLabel.Text = '.stl read fn';
            app.progressLabel = uilabel(app.BMMtFUSSimulatorUIFigure);
            app.progressLabel.Text = 'Ready';
            app.CollectionvolumePanel = uipanel(app.BMMtFUSSimulatorUIFigure);
            app.CollectionvolumePanel.Title = '8. Collection volume';
            app.xViewMinEdit = uieditfield(app.CollectionvolumePanel, 'numeric');
            app.xViewMinEdit.Value = -8;
            app.xViewMaxEdit = uieditfield(app.CollectionvolumePanel, 'numeric');
            app.xViewMaxEdit.Value = 8;
            app.yViewMinEdit = uieditfield(app.CollectionvolumePanel, 'numeric');
            app.yViewMinEdit.Value = -9;
            app.yViewMaxEdit = uieditfield(app.CollectionvolumePanel, 'numeric');
            app.yViewMaxEdit.Value = 8;
            app.zViewMinEdit = uieditfield(app.CollectionvolumePanel, 'numeric');
            app.zViewMinEdit.Value = -12;
            app.zViewMaxEdit = uieditfield(app.CollectionvolumePanel, 'numeric');
            app.zViewMaxEdit.Value = 5;
            app.xmmLabel_3 = uilabel(app.CollectionvolumePanel);
            app.xmmLabel_3.Text = 'x [mm]';
            app.ymmLabel_3 = uilabel(app.CollectionvolumePanel);
            app.ymmLabel_3.Text = 'y [mm]';
            app.zmmLabel_3 = uilabel(app.CollectionvolumePanel);
            app.zmmLabel_3.Text = 'z [mm]';
            app.Label_4 = uilabel(app.CollectionvolumePanel);
            app.Label_4.Text = '~';
            app.Label_5 = uilabel(app.CollectionvolumePanel);
            app.Label_5.Text = '~';
            app.Label_6 = uilabel(app.CollectionvolumePanel);
            app.Label_6.Text = '~';
            app.collectAutoButton = uibutton(app.CollectionvolumePanel, 'push');
            app.collectAutoButton.ButtonPushedFcn = createCallbackFcn(app, @collectAutoButtonPushed, true);
            app.collectAutoButton.Text = 'Auto';
            % Show the figure after all components are created
            app.BMMtFUSSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAIN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BMMtFUSSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BMMtFUSSimulatorUIFigure)
        end
    end
end